classdef Beam_analyzation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        Image13                        matlab.ui.control.Image
        developedbyFriedParrotLabel    matlab.ui.control.Label
        FEMAnalysisandOptimizationforBeamLabel  matlab.ui.control.Label
        ExitButton                     matlab.ui.control.Button
        AboutButton                    matlab.ui.control.Button
        LibraryButton                  matlab.ui.control.Button
        PreferencesButton              matlab.ui.control.Button
        OpenexistinganalyzationButton  matlab.ui.control.Button
        StartNewAnalyzationButton      matlab.ui.control.Button
        FollowmeonLabel                matlab.ui.control.Label
        Image12                        matlab.ui.control.Image
        Image11                        matlab.ui.control.Image
        Image10                        matlab.ui.control.Image
        Image9                         matlab.ui.control.Image
        Image8                         matlab.ui.control.Image
        Image7                         matlab.ui.control.Image
        Image6                         matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
        Image4                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        ModelingTab                    matlab.ui.container.Tab
        MeshSettingsPanel              matlab.ui.container.Panel
        MeshQualityControlLabel        matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        ClearMeshButton                matlab.ui.control.Button
        MeshButton                     matlab.ui.control.Button
        PropertiesPanel                matlab.ui.container.Panel
        StandardPolygonPanel           matlab.ui.container.Panel
        rotateangleEditField           matlab.ui.control.NumericEditField
        rotateangleEditFieldLabel      matlab.ui.control.Label
        deleteButton                   matlab.ui.control.Button
        CreatingMethodDropDown         matlab.ui.control.DropDown
        CreatingMethodDropDownLabel    matlab.ui.control.Label
        radiusEditField_2              matlab.ui.control.NumericEditField
        radiusEditField_2Label         matlab.ui.control.Label
        EdgeNumberEditField            matlab.ui.control.NumericEditField
        EdgeNumberEditFieldLabel       matlab.ui.control.Label
        EllipsePanel                   matlab.ui.container.Panel
        DeleteButton_3                 matlab.ui.control.Button
        RotateangleEditField           matlab.ui.control.NumericEditField
        RotateangleEditFieldLabel      matlab.ui.control.Label
        MinorradiusEditField           matlab.ui.control.NumericEditField
        MinorradiusEditFieldLabel      matlab.ui.control.Label
        MajorradiusEditField           matlab.ui.control.NumericEditField
        MajorradiusEditFieldLabel      matlab.ui.control.Label
        CenterLabel                    matlab.ui.control.Label
        yEditField_3                   matlab.ui.control.NumericEditField
        yEditField_3Label              matlab.ui.control.Label
        xEditField_3                   matlab.ui.control.NumericEditField
        xEditField_3Label              matlab.ui.control.Label
        CirclePanel                    matlab.ui.container.Panel
        DeleteButton_2                 matlab.ui.control.Button
        radiusEditField                matlab.ui.control.NumericEditField
        radiusEditFieldLabel           matlab.ui.control.Label
        CenterlocationLabel            matlab.ui.control.Label
        yEditField_2                   matlab.ui.control.NumericEditField
        yEditField_2Label              matlab.ui.control.Label
        xEditField_2                   matlab.ui.control.NumericEditField
        xEditField_2Label              matlab.ui.control.Label
        RectanglePanel                 matlab.ui.container.Panel
        DeleteButton                   matlab.ui.control.Button
        locatemethodDropDown           matlab.ui.control.DropDown
        locatemethodDropDownLabel      matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        widthEditField                 matlab.ui.control.NumericEditField
        widthEditFieldLabel            matlab.ui.control.Label
        UserDefinedPolygonPanel        matlab.ui.container.Panel
        DeleteButton_4                 matlab.ui.control.Button
        EditPointsButton               matlab.ui.control.Button
        NoPropertytoDisplayLabel       matlab.ui.control.Label
        CrossSectionPanel              matlab.ui.container.Panel
        PlaneGeometryListLabel         matlab.ui.control.Label
        CreateButton                   matlab.ui.control.Button
        AddComponentDropDown           matlab.ui.control.DropDown
        ListBox                        matlab.ui.control.ListBox
        GeometryplotPanel              matlab.ui.container.Panel
        ClearExtrudeButton             matlab.ui.control.Button
        ExtrudemmLabel                 matlab.ui.control.Label
        ExtrudeEditField               matlab.ui.control.NumericEditField
        BuildBeamGeometryButton        matlab.ui.control.Button
        ViewSectionButton              matlab.ui.control.Button
        PhysicsDefinitionTab           matlab.ui.container.Tab
        StudyCalculationPanel          matlab.ui.container.Panel
        ConfirmButton                  matlab.ui.control.Button
        MaterialDropDown               matlab.ui.control.DropDown
        MaterialDropDownLabel          matlab.ui.control.Label
        CalculateStudyButton           matlab.ui.control.Button
        IncludeGeometricNonlinearityCheckBox  matlab.ui.control.CheckBox
        BoundarySettingsPanel          matlab.ui.container.Panel
        BoundarySelectionLabel         matlab.ui.control.Label
        NothingtoDisplayLabel          matlab.ui.control.Label
        DomainSelectionListBox         matlab.ui.control.ListBox
        DefineLoadvectorPanel          matlab.ui.container.Panel
        DefineForceVectorButton        matlab.ui.control.Button
        MethodDropDown                 matlab.ui.control.DropDown
        MethodDropDownLabel            matlab.ui.control.Label
        ReplotSelectionButton          matlab.ui.control.Button
        BoundarySelectionListBox       matlab.ui.control.ListBox
        ClearButton                    matlab.ui.control.Button
        SelectAllButton                matlab.ui.control.Button
        MoreOptionsPanel               matlab.ui.container.Panel
        SaveModelButton                matlab.ui.control.Button
        OpenModelInComsolButton        matlab.ui.control.Button
        BoundaryConditionPanel         matlab.ui.container.Panel
        DeleteButton_5                 matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        BoundaryConditionDropDown      matlab.ui.control.DropDown
        LoadListListBox                matlab.ui.control.ListBox
        LoadListListBoxLabel           matlab.ui.control.Label
        optimizationTab                matlab.ui.container.Tab
        TheOptimizationTabiscurrentlyonconstrucionLabel  matlab.ui.control.Label
        ResultsTab                     matlab.ui.container.Tab
        SavepictureButton              matlab.ui.control.StateButton
        PlotResultButton               matlab.ui.control.StateButton
        PlotSettingsPanel              matlab.ui.container.Panel
        ColorMapDropDown               matlab.ui.control.DropDown
        ColorMapDropDownLabel          matlab.ui.control.Label
        PlotParameterDropDown          matlab.ui.control.DropDown
        PlotParameterDropDownLabel     matlab.ui.control.Label
        DeformationfactorEditField     matlab.ui.control.NumericEditField
        DeformationfactorEditFieldLabel  matlab.ui.control.Label
        PlotDeformationCheckBox        matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
        helpTab                        matlab.ui.container.Tab
        Image19                        matlab.ui.control.Image
        Image18                        matlab.ui.control.Image
        Image17                        matlab.ui.control.Image
        Image16                        matlab.ui.control.Image
        Image15                        matlab.ui.control.Image
        Image14                        matlab.ui.control.Image
        ReportBugsContactusButton      matlab.ui.control.Button
        GuideDocumentationButton       matlab.ui.control.Button
        ExamplesLibraryButton          matlab.ui.control.Button
    end


    properties (Access = private)
        model = nan % entire model of the Application 
        dark = 1 % If the application is displayed on the dark mode 
        GeomList = [] % List of all geometries in the Application
        SettingDialogAPP % Description
        ModelNotCreatedDialogAPP % Description
        GlobalDomainSelection % Description
        GlobalBoundarySelection % Description
        LoadList % contains all the boundary condition that created

    end
    
    methods (Access = public) 
        % use the static method here for called by settings Dialog
        function ToggleDarkMode(app, darkvalue)
            % if value = 0, Toggle LightMode
            app.dark = darkvalue; % we must store the input first
            if app.dark == 0
                app.Image.Enable = false; app.Image.Visible = false;
                app.Image3.Enable = false; app.Image3.Visible = false; 
                app.Image5.Enable = false; app.Image5.Visible = false;
                app.Image7.Enable = false; app.Image7.Visible = false;
                app.Image9.Enable = false; app.Image9.Visible = false;
                app.Image11.Enable = false; app.Image11.Visible = false;
                app.Image17.Enable = false; app.Image17.Visible = false;
                app.Image18.Enable = false; app.Image18.Visible = false;
                app.Image19.Enable = false; app.Image19.Visible = false;
                
                app.Image2.Enable = true; app.Image2.Visible = true;
                app.Image4.Enable = true; app.Image4.Visible = true;
                app.Image6.Enable = true; app.Image6.Visible = true;
                app.Image8.Enable = true; app.Image8.Visible = true;
                app.Image10.Enable = true; app.Image10.Visible = true;
                app.Image12.Enable = true; app.Image12.Visible = true;
                app.Image14.Enable = true; app.Image14.Visible = true;
                app.Image15.Enable = true; app.Image15.Visible = true;
                app.Image16.Enable = true; app.Image16.Visible = true;
                % ---------  modeling panel ----------
                app.CrossSectionPanel.BackgroundColor = [0.70,0.70,0.73]; 
                app.GeometryplotPanel.BackgroundColor = [0.70,0.70,0.73]; 
                app.PropertiesPanel.BackgroundColor = [0.70,0.70,0.73];
                app.MeshSettingsPanel.BackgroundColor = [0.70,0.70,0.73];
                app.PlaneGeometryListLabel.BackgroundColor = [0.65,0.65,0.65];
                app.ListBox.BackgroundColor = [0.85,0.85,0.85];
                app.RectanglePanel.BackgroundColor = [0.70,0.70,0.73];
                app.CirclePanel.BackgroundColor = [0.70,0.70,0.73];
                app.EllipsePanel.BackgroundColor = [0.70,0.70,0.73];
                app.StandardPolygonPanel.BackgroundColor = [0.70,0.70,0.73];
                app.UserDefinedPolygonPanel.BackgroundColor = [0.70,0.70,0.73];
                % ---------- Physics Panel  ----------
                app.StudyCalculationPanel.BackgroundColor = [0.70,0.70,0.73];
                app.BoundarySettingsPanel.BackgroundColor = [0.70,0.70,0.73];
                app.MoreOptionsPanel.BackgroundColor = [0.70,0.70,0.73];
                app.BoundaryConditionPanel.BackgroundColor = [0.70,0.70,0.73];
                app.DefineLoadvectorPanel.BackgroundColor = [0.70,0.70,0.73];
                app.LoadListListBox.BackgroundColor = [0.85,0.85,0.85];
                app.DomainSelectionListBox.BackgroundColor = [0.85,0.85,0.85];
                app.BoundarySelectionListBox.BackgroundColor = [0.85,0.85,0.85];

                for i = 1: 6
                    tab = app.TabGroup.Children(i);
                    tab.BackgroundColor = [0.86,0.79,0.79];
                end 
            end
            if app.dark == 1
                app.Image.Enable = true; app.Image.Visible = true;
                app.Image3.Enable = true; app.Image3.Visible = true; 
                app.Image5.Enable = true; app.Image5.Visible = true;
                app.Image7.Enable = true; app.Image7.Visible = true;
                app.Image9.Enable = true; app.Image9.Visible = true;
                app.Image11.Enable = true; app.Image11.Visible = true; 
                app.Image17.Enable = true; app.Image17.Visible = true;
                app.Image18.Enable = true; app.Image18.Visible = true;
                app.Image19.Enable = true; app.Image19.Visible = true;

                app.Image2.Enable = false; app.Image2.Visible = false;
                app.Image4.Enable = false; app.Image4.Visible = false;
                app.Image6.Enable = false; app.Image6.Visible = false;
                app.Image8.Enable = false; app.Image8.Visible = false;
                app.Image10.Enable = false; app.Image10.Visible = false;
                app.Image12.Enable = false; app.Image12.Visible = false;
                app.Image14.Enable = false; app.Image14.Visible = false;
                app.Image15.Enable = false; app.Image15.Visible = false;
                app.Image16.Enable = false; app.Image16.Visible = false;
                % ---------  modeling panel ----------
                app.CrossSectionPanel.BackgroundColor = [0.30,0.30,0.33]; 
                app.GeometryplotPanel.BackgroundColor = [0.30,0.30,0.33]; 
                app.PropertiesPanel.BackgroundColor = [0.30,0.30,0.33]; 
                app.MeshSettingsPanel.BackgroundColor = [0.30,0.30,0.33];
                app.PlaneGeometryListLabel.BackgroundColor = [0.41,0.38,0.38];
                app.ListBox.BackgroundColor = [0.50,0.50,0.50];
                app.RectanglePanel.BackgroundColor = [0.30,0.30,0.33]; 
                app.CirclePanel.BackgroundColor = [0.30,0.30,0.33]; 
                app.EllipsePanel.BackgroundColor = [0.30,0.30,0.33]; 
                app.StandardPolygonPanel.BackgroundColor = [0.30,0.30,0.33]; 
                app.UserDefinedPolygonPanel.BackgroundColor = [0.30,0.30,0.33]; 
                % ---------- Physics Panel  ----------
                app.StudyCalculationPanel.BackgroundColor = [0.30,0.30,0.33];
                app.BoundarySettingsPanel.BackgroundColor = [0.30,0.30,0.33];
                app.MoreOptionsPanel.BackgroundColor = [0.30,0.30,0.33];
                app.BoundaryConditionPanel.BackgroundColor = [0.30,0.30,0.33];
                app.DefineLoadvectorPanel.BackgroundColor = [0.30,0.30,0.33];
                app.DomainSelectionListBox.BackgroundColor = [0.50,0.50,0.50];
                app.LoadListListBox.BackgroundColor = [0.50,0.50,0.50];
                app.BoundarySelectionListBox.BackgroundColor = [0.50,0.50,0.50];
                for i = 1 : 6
                    tab = app.TabGroup.Children(i);
                    tab.BackgroundColor = [0.25,0.24,0.26];
                end 
            end
            app.PreferencesButton.Enable = 'off';
        end
        
        function ReturnHome(app)
            app.TabGroup.SelectedTab = app.HomeTab;
            app.ExitDialogAPPs();
        end
        
        function CreateNewModel(app)
            app.TabGroup.SelectedTab = app.ModelingTab;
            app.StartNewFunc(app);
        end
        
        function CreatePolygen(app, PointTable, PolyName, self_index, Items)
            try 
                disp(join(["Create:",PolyName]))
                app.model.component('comp1').geom('geom1').feature('wp1').geom.create(PolyName, 'Polygon');
                app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(PolyName).set('source', 'table');
                for i = 1:length(PointTable)
                    app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(PolyName).setIndex('table', PointTable(i,1), i-1, 0);
                    app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(PolyName).setIndex('table', PointTable(i,2), i-1, 1);
                end
                app.model.component('comp1').geom('geom1').feature('wp1').geom.run(PolyName);
                % ----- refersh the Geometry List ------
                app.GeomList.TypeList(app.GeomList.length + 1) = "pol";
                app.GeomList.IndexList = [app.GeomList.IndexList, self_index]; 
                % the index list is the list of self index
                app.GeomList.length = app.GeomList.length + 1;
                % ----- add properties -------
                Property = struct();
                Property.index = self_index;
                Property.PointTable = PointTable; %  n x 2 array
                Property.Items = Items;
                app.GeomList.UserPolyData(self_index) = Property;
                % ----- Append the Geometry into the ListBox ----
                app.ListBox.Items = [app.ListBox.Items, PolyName];
                app.ListBox.Value = app.ListBox.Items(length(app.ListBox.Items));
                RefreshSectionPlot(app);
                ChangePreferenceSettings(app,app.GeomList.length);
            catch
                msgbox("Can't Create the Polygon, Check your Point Input");
            end
        end
        
        function EditPolygen(app,  PolyName, PointTable, Items, self_index)
           app.GeomList.UserPolyData.index(self_index) = self_index;
           app.GeomList.UserPolyData.Items(self_index) = Items;
           app.GeomList.UserPolyData.PointTable(self_index) = PointTable;
           for i = 1:length(PointTable)
               app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(PolyName).setIndex('table', PointTable(i,1), i-1, 0);
               app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(PolyName).setIndex('table', PointTable(i,2), i-1, 1);
           end
           app.model.component('comp1').geom('geom1').feature('wp1').geom.run(PolyName);
        end
        
        function SetForceVector(app, ForceVector, LoadSelfIndex, LoadType, LoadTotName)
            if LoadType == "bndl"
                % storge the data into database
                app.LoadList.BdLoadData(LoadSelfIndex).LoadVector = ForceVector;  
                
                % set the load vector
                if app.LoadList.BdLoadData(LoadSelfIndex).LoadType == "TotalForce"
                    app.model.component('comp1').physics('solid').feature(LoadTotName).set('Ftot', ForceVector);
                end
                if app.LoadList.BdLoadData(LoadSelfIndex).LoadType == "ForceArea"
                    app.model.component('comp1').physics('solid').feature(LoadTotName).set('FperArea', ForceVector);
                end
            end

            if LoadType == "bl"
                app.LoadList.VolFLoadData(LoadSelfIndex).LoadVector = ForceVector;
                app.model.component('comp1').physics('solid').feature(LoadTotName).set('FperVol', ForceVector);
            end
        end
    end
    
    methods (Access = private)
        function WebSearchFunc(~, str)
            if str == "twitter"
               web("https://twitter.com/FriedParrot",'-browser');
            end
            if str == "facebook"
                web("https://www.facebook.com/profile.php?id=100076795685771",'-browser')
            end
            if str == "github"
               web("https://github.com/FRIEDparrot",'-browser')
            end
            if str == "bili"
                web("https://space.bilibili.com/1185195559",'-browser')
            end
            if str == "wechat"
                web("https://mp.weixin.qq.com/cgi-bin/home?t=home/index&token=852263035&lang=zh_CN",'-browser')
            end
            if str == "CSDN"
                web("https://blog.csdn.net/sbsbsb666666",'-browser')
            end
        end
        
        function RefreshSectionPlot(app)
            try
                mphgeom(app.model,'geom1','WorkPlane','wp1');
            catch
                try
                    if isnan(app.model)
                    app.ModelNotCreatedDialogAPP = ModelNotCreatedDialog(app,app.dark);
                    end
                catch
                    msgbox("create a geometry before plot")
                end
            end
        end
        
        function RefreshVolumePlot(app)
            try
                app.model.component('comp1').geom('geom1').run('fin');
                mphgeom(app.model,'geom1');
            catch
                try
                    if isnan(app.model)
                    app.ModelNotCreatedDialogAPP = ModelNotCreatedDialog(app,app.dark);
                    end
                catch
                    msgbox("create a geometry before plot");
                end
            end
        end            
        
        function result = AutoGenerateIndex(app, type)
            index = 0;
            % GeomList has 3 properties, which is "TypeList","IndexList",
            % and "Identity List", from the "Identity List" we can get the
            % properties and set it in the property window 
            % "TypeList is a CharArray", and we can just get the name by
            % combining the NameList and the Identity List. 
            if isempty(app.GeomList)
                % do nothing and set index to 1; 
            else
                for i = 1: app.GeomList.length
                    if type == app.GeomList.TypeList(i)
                        if index < app.GeomList.IndexList(i)
                            index = app.GeomList.IndexList(i); 
                        end
                    end
                end
            end
            result = index +1;
        end

        function result = AutoGenerateIndex2(app, type)
            index = 0;
            % GeomList has 3 properties, which is "TypeList","IndexList",
            % and "Identity List", from the "Identity List" we can get the
            % properties and set it in the property window 
            % "TypeList is a CharArray", and we can just get the name by
            % combining the NameList and the Identity List. 
            if isempty(app.LoadList)
                % do nothing and set index to 1; 
            else
                for i = 1: app.LoadList.length
                    if type == app.LoadList.TypeList(i)
                        if index < app.LoadList.IndexList(i)
                            index = app.LoadList.IndexList(i);
                        end
                    end
                end
            end
            result = index +1;
        end
        
        % this function is to refresh the property settings window
        
        function ExitDialogAPPs(app)
            % Note that in the callback function,
            % the parameter "app" need not to be transfered into it.
            try
                app.ModelNotCreatedDialogAPP.delete();
            catch % no dialog to create 
            end
            try
                app.SettingDialogAPP.delete();
            catch 
            end
        end
        
        function CreatePlaneGeometry(app,Value)
            if Value == "Line Segments"
               % firstly we use another APP to generate a table and ask
               % user to input, then we can generate the polygen by
               % this 
               index = AutoGenerateIndex(app,"pol");
               PolName = ['pol', num2str(index)];
               Ask_Polygon_points(app,app.dark,PolName,index,nan);
            else
                try
                    if Value == "none"
                    end
                    if Value == "Rectangle"
                        index = AutoGenerateIndex(app,"r");
                        RectName = ['r',num2str(index)];  % if it is the rectangle it is r1
                        disp (join(["Created:",RectName]));
                        app.model.component("comp1").geom("geom1").feature('wp1').geom.create(RectName,'Rectangle');
                        app.model.component("comp1").geom("geom1").feature('wp1').geom.feature(RectName).set('base','center');
                        % ----- refersh the Geometry List ------
                        app.GeomList.TypeList(app.GeomList.length + 1) = "r";
                        app.GeomList.IndexList = [app.GeomList.IndexList, index];
                        app.GeomList.length = app.GeomList.length + 1;
                        % ----- initialize and storge the properties -----
                        Property = struct();
                        Property.index = index; % give index at that time;
                        Property.creating_method = "Center";
                        Property.width = 1;
                        Property.height = 1;
                        Property.anchor.x = 0;
                        Property.anchor.y = 0;
                        % ----- concatenate the property to the RectData -
                        app.GeomList.RectData(index) = Property;
                        % ----- Append the Geometry into the ListBox -----
                        app.ListBox.Items = [app.ListBox.Items, RectName];
                    end
                    if Value == "Circle"
                        index = AutoGenerateIndex(app,"c");
                        CircName = ['c', num2str(index)];
                        disp(join(["Created: ", CircName]));
                        app.model.component("comp1").geom("geom1").feature("wp1").geom.create(CircName,'Circle'); 
                        app.GeomList.TypeList(app.GeomList.length + 1) = "c";
                        app.GeomList.IndexList = [app.GeomList.IndexList, index];
                        app.GeomList.length = app.GeomList.length + 1;
                        % --- concatnate the property ----
                        Property = struct();
                        Property.index = index; % give index at that time;
                        Property.radius = 1;
                        Property.anchor.x = 0;
                        Property.anchor.y = 0;
                        app.GeomList.CircData(index) = Property;
                        % --- append geometry to list ----
                        app.ListBox.Items = [app.ListBox.Items, CircName];
                    end
                    if Value == "Ellipse"
                        index = AutoGenerateIndex(app,"e");
                        ElliName = ['e', num2str(index)];
                        disp(join(['Created: ', ElliName]));
                        app.model.component("comp1").geom("geom1").feature("wp1").geom.create(ElliName,'Ellipse'); 
                        app.GeomList.TypeList(app.GeomList.length + 1) = "e";
                        app.GeomList.IndexList = [app.GeomList.IndexList, index];
                        app.GeomList.length = app.GeomList.length + 1;
                        app.ListBox.Items = [app.ListBox.Items, ElliName];
                        % ----- property ---------------
                        Property = struct();
                        Property.index = index;
                        Property.anchor.x = 0;
                        Property.anchor.y = 0;
                        Property.major_radius = 1;
                        Property.minor_radius = 1;
                        Property.rotate_angle = 0;
                        app.GeomList.ElliData(index) = Property;
                    end
                    if Value == "Polygon"
                        index = AutoGenerateIndex(app,"pol");
                        PolName = ['pol', num2str(index)];
                        disp(join(['Created: ', PolName]));
                        app.model.component("comp1").geom("geom1").feature("wp1").geom.create(PolName,'Polygon');
                        app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(PolName).set('source', 'table');
                        % create a triangle with circumcircle radius 1 (ratate 0 degree) as default 
                        CreatePolygon(app,PolName, 3, 1.0 , 0 , "outer");
                        app.model.component('comp1').geom('geom1').feature('wp1').geom.run(PolName);
                        app.GeomList.TypeList(app.GeomList.length + 1) = "pol";
                        app.GeomList.IndexList = [app.GeomList.IndexList, index];
                        app.GeomList.length = app.GeomList.length + 1;
                        %------- initial property ---------
                        Property = struct();
                        Property.index = index;
                        Property.creating_method = "Circumscribed circle";
                        Property.edge_number = 3;
                        Property.radius = 1;
                        Property.rotate_angle = 0;
                        app.GeomList.StdPolyData(index) = Property;
                        %-------append to list -------
                        app.ListBox.Items = [app.ListBox.Items, PolName];
                    end
                    % Change the selection to the newly created geometry
                    app.ListBox.Value = app.ListBox.Items(length(app.ListBox.Items));
                    ChangePreferenceSettings(app,app.GeomList.length);
                catch
                    % alert in the RefreshSectionPlot
                end
                RefreshSectionPlot(app);
            end
        end
        
        function CreatePolygon(app, polyName ,edgenum, radius, rotateangular ,method)
            if method == "outer"
                for i = 0:edgenum-1     % row, and two columns in each row
                    y = radius * cos(i * 2 * pi/ edgenum + rotateangular);
                    x = radius * -sin(i * 2 * pi/ edgenum + rotateangular); 
                    app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(polyName).setIndex('table', x, i, 0);
                    app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(polyName).setIndex('table', y, i, 1);
                end
            end
            if method == "inner"
                for i = 0:edgenum-1
                    radius_new = radius / cos(pi/edgenum); 
                    y = radius_new * cos(i * 2*pi/edgenum + rotateangular* pi/180);
                    x = radius_new * -sin(i * 2*pi/edgenum + rotateangular* pi/180); 
                    app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(polyName).setIndex('table', x, i, 0);
                    app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(polyName).setIndex('table', y, i, 1);
                end
            end
        end
        
        % this function is just used for replot the standard polygon
        function RePlotPolygon(app)
            % the index is the index in the list 
            r = getSelectedNameAndIndex(app);
            polyName = r(1); self_index = str2double(r(3));

            radius = app.GeomList.StdPolyData(self_index).radius;
            edgenum = app.GeomList.StdPolyData(self_index).edge_number;
            method = app.GeomList.StdPolyData(self_index).creating_method;
            rotateangular = app.GeomList.StdPolyData(self_index).rotate_angle;
            
            disp(method)
            disp("Detected"); 
            if method == "Circumscribed circle"  % outer
                for i = 0:edgenum-1     % row, and two columns in each row
                    y = radius * cos(i * 2 * pi/ edgenum + rotateangular * pi/180);
                    x = radius * -sin(i * 2 * pi/ edgenum + rotateangular * pi/180); 
                    disp("point");disp(x);disp(y);
                    app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(polyName).setIndex('table', x, i, 0);
                    app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(polyName).setIndex('table', y, i, 1);
                end
            end
            if method == "Inscribed circle"
                for i = 0:edgenum-1
                    radius_new = radius / cos(pi/edgenum); 
                    y = radius_new * cos(i * 2*pi/edgenum + rotateangular * pi/180);
                    x = radius_new * -sin(i * 2*pi/edgenum + rotateangular * pi/180); 
                    disp("point");disp(x);disp(y);
                    app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(polyName).setIndex('table', x, i, 0);
                    app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(polyName).setIndex('table', y, i, 1);
                end
            end
            app.model.component('comp1').geom('geom1').feature('wp1').geom.run(polyName);
        end
        
        function ChangePreferenceSettings(app, index)
            % index there is index in the element 
            % --- judge the TypeName and the Geometry Name ------
            TypeName = app.GeomList.TypeList(index);
            % firstly , Close all the tab in the Properties Window
            app.NoPropertytoDisplayLabel.Enable = 'Off'; 
            app.NoPropertytoDisplayLabel.Visible = 'Off';
            app.RectanglePanel.Enable = 'Off'; 
            app.RectanglePanel.Visible = 'Off'; 
            app.EllipsePanel.Enable = 'Off';
            app.EllipsePanel.Visible = 'Off';
            app.CirclePanel.Enable = 'Off';
            app.CirclePanel.Visible = 'Off';
            app.StandardPolygonPanel.Enable = 'Off';
            app.StandardPolygonPanel.Visible = 'Off';
            app.UserDefinedPolygonPanel.Enable = 'Off';
            app.UserDefinedPolygonPanel.Visible = 'Off';

            % --- then add the corresponding preference panel and load data
            if TypeName == "r"
                app.RectanglePanel.Enable = 'On';
                app.RectanglePanel.Visible = 'On';
                % --- load data from the property -----
                self_index = app.GeomList.IndexList(index);
                DataList = app.GeomList.RectData(self_index);
                app.locatemethodDropDown.Value = DataList.creating_method;
                app.xEditField.Value = DataList.anchor.x;
                app.yEditField.Value = DataList.anchor.y;
                app.widthEditField.Value = DataList.width;
                app.HeightEditField.Value = DataList.height;
            end
            if TypeName == "c"
                app.CirclePanel.Enable = 'On';
                app.CirclePanel.Visible = 'On'; 
                % --- load data ---
                self_index = app.GeomList.IndexList(index);
                DataList = app.GeomList.CircData(self_index);
                app.xEditField_2.Value = DataList.anchor.x;
                app.yEditField_2.Value = DataList.anchor.y;
                app.radiusEditField.Value = DataList.radius;
            end
            if TypeName == "e"
                app.EllipsePanel.Enable = 'On'; 
                app.EllipsePanel.Visible = 'On';
                self_index = app.GeomList.IndexList(index);
                DataList = app.GeomList.ElliData(self_index);
                app.xEditField_3.Value = DataList.anchor.x; 
                app.yEditField_3.Value = DataList.anchor.y;
                app.MajorradiusEditField.Value = DataList.major_radius;
                app.MinorradiusEditField.Value = DataList.minor_radius;
                app.RotateangleEditField.Value = DataList.rotate_angle;
            end
            if TypeName == "pol"
                self_index = app.GeomList.IndexList(index);
                try
                    if ~isnan(app.GeomList.StdPolyData(self_index).index)
                        disp("Standard Polygon");
                        app.StandardPolygonPanel.Enable = 'On';
                        app.StandardPolygonPanel.Visible = 'On';
                        % ------ load the properties -----
                        app.EdgeNumberEditField.Value = app.GeomList.StdPolyData(self_index).edge_number;
                        app.radiusEditField_2.Value = app.GeomList.StdPolyData(self_index).radius; 
                        app.rotateangleEditField.Value = app.GeomList.StdPolyData(self_index).rotate_angle;
                    else 
                        app.UserDefinedPolygonPanel.Enable = 'On';
                        app.UserDefinedPolygonPanel.Visible = 'On';
                    end
                catch
                    app.UserDefinedPolygonPanel.Enable = 'On';
                    app.UserDefinedPolygonPanel.Visible = 'On';
                end
            end
        end
        
        % where "index" is the index in the element
        function results = getSelectedNameAndIndex(app)
            % get the name and index in the results
            GeomName = string(app.ListBox.Value);
            for i = 1:app.GeomList.length
                RealGeomName = char(join([app.GeomList.TypeList(i),num2str(app.GeomList.IndexList(i))],''));
                if GeomName == RealGeomName
                    break;
                end
            end
            index = i;
            self_index = app.GeomList.IndexList(index);
            results = [GeomName,index,self_index];
        end
        
        % index is index in the List
        function results = FindGeometryIndex(app,GeoName)
            for i = 1:length(app.ListBox.Items)
                if GeoName == string(app.ListBox.Items(i))
                    break;
                end
            end
            results = i;
        end
        
        function SetMeshSize(app, precision)
            if (precision == "Normal")
                app.model.component('comp1').mesh('mesh1').autoMeshSize(5);
            end
            if (precision == "Fine")
                app.model.component('comp1').mesh('mesh1').autoMeshSize(4);
            end
            if (precision == "Finer")
                app.model.component('comp1').mesh('mesh1').autoMeshSize(3);
            end
            if (precision == "Extra Fine")
                app.model.component('comp1').mesh('mesh1').autoMeshSize(2);
            end
            if (precision == "Extremely Fine")
                app.model.component('comp1').mesh('mesh1').autoMeshSize(1);
            end
            if (precision == "Coarse")
                app.model.component('comp1').mesh('mesh1').autoMeshSize(6);
            end
            if (precision == "Coarser")
                app.model.component('comp1').mesh('mesh1').autoMeshSize(7);
            end
            if (precision == "Extra Coarse")
                app.model.component('comp1').mesh('mesh1').autoMeshSize(8);
            end
            if (precision == "Extremely Coarse")
                app.model.component('comp1').mesh('mesh1').autoMeshSize(8);
            end
        end 
        
        % ===========Physics Domain =================
        function RefreshBoundarySettings(app)
            % this function is to judge what to display in the boundary
            % settings panel
            r = getSelectedLoadNameAndIndex(app);
            Index = str2double(r(2));
            Choose = app.LoadList.TypeList(Index);
            % boundary is the face and the domain is the volume 
            % note that the block of code there can be optimized as only
            % one SelectionListBox
            app.BoundarySelectionListBox.Enable = false;
            app.BoundarySelectionListBox.Visible = false;
            app.DomainSelectionListBox.Enable = false;
            app.DomainSelectionListBox.Visible = false;
            app.NothingtoDisplayLabel.Enable = false;
            app.NothingtoDisplayLabel.Visible = false;
            SetButtonVisible(app, false, false); 
            app.BoundarySelectionLabel.Visible = false;
            app.BoundarySelectionLabel.Enable = false;

            try % test if the selection is empty
                isnan(Choose)
                app.NothingtoDisplayLabel.Visible = true; 
                app.NothingtoDisplayLabel.Enable = true;
            catch
                app.BoundarySelectionLabel.Visible = true;
                app.BoundarySelectionLabel.Enable = true;
                app.BoundarySelectionListBox.Enable = true;
                app.BoundarySelectionListBox.Visible = true;
                app.BoundarySelectionLabel.Text = "Boundary Selection";
            end
            if Choose == "fix"
                LoadMaxBoundary(app);
                SetButtonVisible(app, true, false); 
            end
            if Choose == "roll"
                LoadMaxBoundary(app);
                SetButtonVisible(app, true, false);
            end
            if Choose == "sym"
                LoadMaxBoundary(app);
                SetButtonVisible(app, true, false );
            end
            if Choose == "bndl"
                LoadMaxBoundary(app);
                SetButtonVisible(app, true, true);
            end
            if Choose == "bl"
                app.BoundarySelectionListBox.Enable = false;
                app.BoundarySelectionListBox.Visible = false;
                app.BoundarySelectionLabel.Text = "Domain Selection";
                app.DomainSelectionListBox.Enable = true;
                app.DomainSelectionListBox.Visible = true;
                LoadMaxDomain(app); % volume force choice;
                SetButtonVisible(app, true, true);
                app.MethodDropDown.Enable = false;
                app.MethodDropDown.Enable = false;
            end
        end
        
        function SetButtonVisible(app, b, c)
            app.ReplotSelectionButton.Enable = b;
            app.ReplotSelectionButton.Visible = b;
            app.SelectAllButton.Enable = b;
            app.SelectAllButton.Visible = b;
            app.ClearButton.Enable = b;
            app.ClearButton.Visible = b;
            if c == true
                app.DefineLoadvectorPanel.Enable = 'On';
                app.DefineLoadvectorPanel.Visible = 'On';
            else
                app.DefineLoadvectorPanel.Enable = 'Off';
                app.DefineLoadvectorPanel.Visible = 'Off';
            end
        end
        
        function result = LoadMaxDomain(app)
            % load maximum boundary(face) index of the domain
            app.DomainSelectionListBox.Items = {''}; % clear the domain selection
            for i = 1:10000
                try
                    app.GlobalDomainSelection.set(i);
                    app.DomainSelectionListBox.Items(i) = {char(string(i))};
                catch
                    break;
                end
            end
            result = i - 1;
        end
        
        function result = LoadMaxBoundary(app)
            % load maximum boundary(face) index of the boundary
            app.BoundarySelectionListBox.Items = {''}; % clear the boundary selection
            for i = 1:10000
                try
                    app.GlobalBoundarySelection.set(i);
                    app.BoundarySelectionListBox.Items(i) = {char(string(i))};
                    % this is the way to append
                catch
                    break;
                end
            end
            result = i-1;
        end
        
        function results = FindLoadIndexInList(app,LoadName)
            for i = 1:length(app.LoadListListBox.Items)
                if LoadName == string(app.LoadListListBox.Items(i))
                    break;
                end
            end
            results = i;
        end
        
        function results = getSelectedLoadNameAndIndex(app)
            LoadName = string(app.LoadListListBox.Value);
            for i = 1:app.LoadList.length
                if LoadName == app.LoadList.NameList(i)
                    break;
                end
            end
            index = i;
            self_index = app.LoadList.IndexList(index);
            results = [LoadName,index,self_index];
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % ------- start the server ----------
            try
                mphstart
            catch
            end
        %% ------- initialize the Geometry List -------
            app.GeomList = [];
            app.GeomList.TypeList = "";
            app.GeomList.IndexList = [];
            app.GeomList.length = 0;
            % ------- Define the DataStructure for each Geometry-------
            % --- Rectangle --------
            app.GeomList.RectData = struct();
            app.GeomList.RectData.creating_method = "Center";
            app.GeomList.RectData.index = nan;
            app.GeomList.RectData.width = 1;
            app.GeomList.RectData.height = 1;
            app.GeomList.RectData.anchor.x = 0;
            app.GeomList.RectData.anchor.y = 0;
            % --- Circle -----------
            app.GeomList.CircData = struct(); 
            app.GeomList.CircData.index = nan; 
            app.GeomList.CircData.radius = 1;
            app.GeomList.CircData.anchor.x = 0;
            app.GeomList.CircData.anchor.y = 0;
            % --- Ellipse ----------
            app.GeomList.ElliData = struct();
            app.GeomList.ElliData.index = nan;
            app.GeomList.ElliData.anchor.x = 0;
            app.GeomList.ElliData.anchor.y = 0;
            app.GeomList.ElliData.major_radius = 1;
            app.GeomList.ElliData.minor_radius = 1;
            app.GeomList.ElliData.rotate_angle = 0;
            % --- Standard Polygon--
            app.GeomList.StdPolyData = struct();
            app.GeomList.StdPolyData.index = nan;
            app.GeomList.StdPolyData.creating_method = "Circumscribed circle";
            app.GeomList.StdPolyData.edge_number = 3;
            app.GeomList.StdPolyData.radius = 1;
            app.GeomList.StdPolyData.rotate_angle = 0;
            % --- User defined Polygon--
            app.GeomList.UserPolyData = struct();
            app.GeomList.UserPolyData.index = nan;
            app.GeomList.UserPolyData.PointTable = zeros(3,2); %  n x 2 array
            app.GeomList.UserPolyData.Items = {};
        %% --------initialize the LoadList------------
            app.LoadList = []; % an array
            app.LoadList.nameList = ""; % string array for example, bnd1
            app.LoadList.TypeList = ""; 
            app.LoadList.IndexList = []; % number array
            app.LoadList.length = 0;
            % -----Data in the LoadList --------
            app.LoadList.FixedData = struct();
            app.LoadList.FixedData.selection = {}; % selection must be stored {} is no selection
            % -----
            app.LoadList.RollerData = struct();
            app.LoadList.RollerData.selection = {};
            % -----
            app.LoadList.SymmData = struct();
            app.LoadList.SymmData.selection = {};
            % -----
            app.LoadList.BdLoadData = struct();
            app.LoadList.BdLoadData.selection = {};
            app.LoadList.BdLoadData.LoadType = "ForceArea"; %Storge the load type
            app.LoadList.BdLoadData.LoadVector = [0,0,0];
            % -----
            app.LoadList.VolFData = struct();
            app.LoadList.VolFData.selection = {};
            app.LoadList.VolFData.LoadVector = [0,0,0];
        end

        % Button pushed function: StartNewAnalyzationButton
        function StartNewFunc(app, event)
            import com.comsol.model.* 
            import com.comsol.model.util.* 

            % -------initialize the basic elements -------
            app.TabGroup.SelectedTab = app.ModelingTab; % change the tab selection 
            app.model = ModelUtil.create('Untitled Model');  % create a global model for the app
            app.model.modelPath(".");
            app.model.component.create('comp1', true);
            app.model.component('comp1').geom.create('geom1', 3);   % initialize a 3D object
            app.model.component('comp1').geom('geom1').lengthUnit("mm"); % set the geometry unit
            app.model.component('comp1').geom('geom1').feature.create('wp1', 'WorkPlane'); 
            app.model.component('comp1').geom('geom1').feature('wp1').set('quickplane', 'yz');
            app.GlobalDomainSelection = app.model.component('comp1').selection.create('sel1','Explicit');
            app.GlobalDomainSelection.geom(3); % domain selection is 3D selection
            app.GlobalBoundarySelection = app.model.component('comp1').selection.create('sel2','Explicit');
            app.GlobalBoundarySelection.geom(2); % boundary selection
            app.model.component('comp1').mesh.create('mesh1');
            app.model.component('comp1').physics.create('solid', 'SolidMechanics', 'geom1');
            app.model.study.create('std1');
                        app.model.study('std1').create('stat', 'Stationary');
            app.model.study('std1').feature('stat').setSolveFor('/physics/solid', true);
            % --------- solution -----------
            app.model.sol.create('sol1');  
            app.model.sol('sol1').study('std1'); % bind it to study 1

            app.model.study('std1').feature('stat').set('notlistsolnum', 1);
            app.model.study('std1').feature('stat').set('notsolnum', 'auto');
            app.model.study('std1').feature('stat').set('listsolnum', 1);
            app.model.study('std1').feature('stat').set('solnum', 'auto');
            
            app.model.sol('sol1').create('st1', 'StudyStep');
            app.model.sol('sol1').feature('st1').set('study', 'std1');
            app.model.sol('sol1').feature('st1').set('studystep', 'stat');
            app.model.sol('sol1').create('v1', 'Variables');
            app.model.sol('sol1').feature('v1').set('control', 'stat');
            app.model.sol('sol1').create('s1', 'Stationary');
            app.model.sol('sol1').feature('s1').feature('aDef').set('cachepattern', true);
            app.model.sol('sol1').feature('s1').create('fc1', 'FullyCoupled');
            app.model.sol('sol1').feature('s1').feature('fc1').set('termonres', 'auto');
            app.model.sol('sol1').feature('s1').feature('fc1').set('reserrfact', 1000);
            app.model.sol('sol1').feature('s1').create('d1', 'Direct');
            app.model.sol('sol1').feature('s1').feature('d1').set('linsolver', 'pardiso');
            app.model.sol('sol1').feature('s1').feature('d1').set('pivotperturb', 1.0E-9);
            app.model.sol('sol1').feature('s1').feature('d1').label('Suggested Direct Solver (solid)');
            app.model.sol('sol1').feature('s1').create('i1', 'Iterative');
            app.model.sol('sol1').feature('s1').feature('i1').set('linsolver', 'gmres');
            app.model.sol('sol1').feature('s1').feature('i1').set('rhob', 400);
            app.model.sol('sol1').feature('s1').feature('i1').set('nlinnormuse', true);
            app.model.sol('sol1').feature('s1').feature('i1').label('Suggested Iterative Solver (solid)');
            app.model.sol('sol1').feature('s1').feature('i1').create('mg1', 'Multigrid');
            app.model.sol('sol1').feature('s1').feature('i1').feature('mg1').set('prefun', 'gmg');
            app.model.sol('sol1').feature('s1').feature('i1').feature('mg1').feature('pr').create('so1', 'SOR');
            app.model.sol('sol1').feature('s1').feature('i1').feature('mg1').feature('pr').feature('so1').set('relax', 0.8);
            app.model.sol('sol1').feature('s1').feature('i1').feature('mg1').feature('po').create('so1', 'SOR');
            app.model.sol('sol1').feature('s1').feature('i1').feature('mg1').feature('po').feature('so1').set('relax', 0.8);
            app.model.sol('sol1').feature('s1').feature('i1').feature('mg1').feature('cs').create('d1', 'Direct');
            app.model.sol('sol1').feature('s1').feature('i1').feature('mg1').feature('cs').feature('d1').set('linsolver', 'pardiso');
            app.model.sol('sol1').feature('s1').feature('i1').feature('mg1').feature('cs').feature('d1').set('pivotperturb', 1.0E-9);
            app.model.sol('sol1').feature('s1').feature('fc1').set('linsolver', 'd1');
            app.model.sol('sol1').feature('s1').feature('fc1').set('termonres', 'auto');
            app.model.sol('sol1').feature('s1').feature('fc1').set('reserrfact', 1000);
            app.model.sol('sol1').feature('s1').feature.remove('fcDef');
            app.model.sol('sol1').attach('std1');
            
            % at that time we don't need to refresh the section 
        end

        % Button pushed function: ExitButton
        function ExitFunc(app, event)
            delete(app);
        end

        % Button pushed function: PreferencesButton
        function PreferencesButtonPushed(app, event)
            app.PreferencesButton.Enable = 'off';
            app.SettingDialogAPP = Settings_Dialog(app,app.dark);
        end

        % Image clicked function: Image7, Image8
        function Image7Clicked(app, event)
            WebSearchFunc(app,"bili");
        end

        % Image clicked function: Image5, Image6
        function Image6Clicked(app, event)
            WebSearchFunc(app,"github");
        end

        % Image clicked function: Image, Image2
        function ImageClicked(app, event)
            WebSearchFunc(app,"twitter");
        end

        % Image clicked function: Image3, Image4
        function Image3Clicked(app, event)
            WebSearchFunc(app,"facebook");
        end

        % Image clicked function: Image10, Image9
        function Image9Clicked(app, event)
            WebSearchFunc(app,"wechat");
        end

        % Image clicked function: Image11, Image12
        function Image11Clicked(app, event)
            WebSearchFunc(app,"CSDN");
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            CreatePlaneGeometry(app,app.AddComponentDropDown.Value);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            % value = app.ListBox.Value;
            % GeomName = string(value);
            r = getSelectedNameAndIndex(app);
            index = str2double(r(2));
            % ---- load data from the app.GeomList.Data and display --
            ChangePreferenceSettings(app,index);
            % note that the "index" is the index in List
            % ---- highlight the current geomrtry automatically ------
            
            %mphviewselection(app.model,Name);
        end

        % Button pushed function: ViewSectionButton
        function ViewSectionButtonPushed(app, event)
            RefreshSectionPlot(app);
        end

        % Value changed function: locatemethodDropDown
        function locatemethodDropDownValueChanged(app, event)
            value = app.locatemethodDropDown.Value;
            r = getSelectedNameAndIndex(app);
            RectName = r(1); self_index = str2double(r(3));
            % --------
            if value == "Center"
                app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(RectName).set('base','center');
                % ------ modify the data storged ---------
                app.GeomList.RectData(self_index).creating_method = "Center";
            end
            if value == "Corner"
                app.model.component("comp1").geom("geom1").feature('wp1').geom.feature(RectName).set('base','corner');
                app.GeomList.RectData(self_index).creating_method = "Corner";
            end
            RefreshSectionPlot(app);
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            x = app.xEditField.Value;
            % ---- refresh the data storged and set the anchor point ----
            r = getSelectedNameAndIndex(app);
            RectName = r(1); self_index = str2double(r(3));
            % -----
            y = app.GeomList.RectData(self_index).anchor.y;
            app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(RectName).set('pos', [x, y]);
            app.GeomList.RectData(self_index).anchor.x = x;
            RefreshSectionPlot(app);
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            y = app.yEditField.Value;
            r = getSelectedNameAndIndex(app);
            RectName = r(1); self_index = str2double(r(3));
            % ---------
            x = app.GeomList.RectData(self_index).anchor.x;
            app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(RectName).set('pos', [x, y]);
            app.GeomList.RectData(self_index).anchor.y = y;
            RefreshSectionPlot(app);
        end

        % Value changed function: widthEditField
        function widthEditFieldValueChanged(app, event)
            r = getSelectedNameAndIndex(app);
            RectName = r(1); self_index = str2double(r(3));
            width = app.widthEditField.Value;
            height = app.GeomList.RectData(self_index).height;
            app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(RectName).set('size',[width, height]);
            app.GeomList.RectData(self_index).width = width;
            RefreshSectionPlot(app);
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            r = getSelectedNameAndIndex(app);
            RectName = r(1); self_index = str2double(r(3));
            height = app.HeightEditField.Value;
            width = app.GeomList.RectData(self_index).width;
            app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(RectName).set('size',[width, height]);
            app.GeomList.RectData(self_index).height = height;
            RefreshSectionPlot(app);
        end

        % Button pushed function: DeleteButton, DeleteButton_2, 
        % DeleteButton_3, DeleteButton_4, deleteButton
        function DeleteGeometry(app, event)
            try
            r = getSelectedNameAndIndex(app);
            GeomName = r(1); 
            index = FindGeometryIndex(app, GeomName); % index in the List
            app.model.component('comp1').geom('geom1').feature('wp1').geom.feature.remove(GeomName);
            app.ListBox.Items(index) = [];  % clear the ListBox
            RefreshSectionPlot(app);
                try
                    ChangePreferenceSettings(app, index);
                catch
                end
            catch
                msgbox("nothing to delete");
            end
            % Note that Clearing the DataBase is not needed because the
            % index would still exists, then the data would remained in the
            % database, it would not be called later.
        end

        % Value changed function: xEditField_2
        function xEditField_2ValueChanged(app, event)
            r = getSelectedNameAndIndex(app);
            CircName = r(1); self_index = str2double(r(3));
            x = app.xEditField_2.Value;
            y = app.GeomList.CircData(self_index).anchor.y;
            app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(CircName).set('pos',[x,y])
            app.GeomList.CircData(self_index).anchor.x = x;
            RefreshSectionPlot(app);
        end

        % Value changed function: yEditField_2
        function yEditField_2ValueChanged(app, event)
            r = getSelectedNameAndIndex(app);
            CircName = r(1); self_index = str2double(r(3));
            x = app.GeomList.CircData(self_index).anchor.x;
            y = app.yEditField_2.Value;
            app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(CircName).set('pos',[x,y])
            app.GeomList.CircData(self_index).anchor.y = y;
            RefreshSectionPlot(app);
        end

        % Value changed function: radiusEditField
        function radiusEditFieldValueChanged(app, event)
            r = getSelectedNameAndIndex(app);
            CircName = r(1); self_index = str2double(r(3));
            radius = app.radiusEditField.Value;
            app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(CircName).set('r', radius);
            app.GeomList.CircData(self_index).radius = radius;
            RefreshSectionPlot(app);
        end

        % Value changed function: xEditField_3
        function xEditField_3ValueChanged(app, event)
            r = getSelectedNameAndIndex(app);
            ElliName = r(1); self_index = strdouble(r(3));
            x = app.xEditField_3.Value;
            y = app.GeomList.ElliData(self_index).anchor.y;
            % ----------
            app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(ElliName).set('pos',[x,y])
            app.GeomList.ElliData(self_index).anchor.x = x;
            RefreshSectionPlot(app)
        end

        % Value changed function: yEditField_3
        function yEditField_3ValueChanged(app, event)
            r = getSelectedNameAndIndex(app);
            ElliName = r(1); self_index = str2double(r(3));
            x = app.GeomList.ElliData(self_index).anchor.x;
            y = app.yEditField_3.Value;
            app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(ElliName).set('pos',[x,y])
            app.GeomList.ElliData(self_index).anchor.y = y;
            RefreshSectionPlot(app);
        end

        % Value changed function: MajorradiusEditField
        function MajorradiusEditFieldValueChanged(app, event)
            r = getSelectedNameAndIndex(app);
            ElliName = r(1); self_index = str2double(r(3));
            ma_radius = app.MajorradiusEditField.Value;
            mi_radius = app.GeomList.ElliData(self_index).minor_radius;
            app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(ElliName).set('semiaxes', [ma_radius, mi_radius]);
            app.GeomList.ElliData(self_index).major_radius = ma_radius; 
            RefreshSectionPlot(app);
        end

        % Value changed function: MinorradiusEditField
        function MinorradiusEditFieldValueChanged(app, event)
            r = getSelectedNameAndIndex(app);
            ElliName = r(1); self_index = str2double(r(3));
            ma_radius = app.GeomList.ElliData(self_index).major_radius;
            mi_radius = app.MinorradiusEditField.Value;
            app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(ElliName).set('semiaxes', [ma_radius, mi_radius]);
            app.GeomList.ElliData(self_index).minor_radius = mi_radius;
            RefreshSectionPlot(app);
        end

        % Value changed function: RotateangleEditField
        function RotateangleEditFieldValueChanged(app, event)
            r = getSelectedNameAndIndex(app);
            ElliName = r(1); self_index = str2double(r(3));
            angle = app.RotateangleEditField.Value;
            app.model.component('comp1').geom('geom1').feature('wp1').geom.feature(ElliName).set('rot', angle);
            app.GeomList.ElliData(self_index).rotate_angle = angle;
            RefreshSectionPlot(app);
        end

        % Value changed function: CreatingMethodDropDown
        function CreatingMethodDropDownValueChanged(app, event)
            r = getSelectedNameAndIndex(app);
            self_index = str2double(r(3));
            creating_method = char(app.CreatingMethodDropDown.Value);
            app.GeomList.StdPolyData(self_index).creating_method = creating_method;
            % -- we firstly storge the data and then replot the polygon --
            RePlotPolygon(app);
            RefreshSectionPlot(app);
        end

        % Value changed function: radiusEditField_2
        function radiusEditField_2ValueChanged(app, event)
            r = getSelectedNameAndIndex(app);
            self_index = str2double(r(3));
            radius = app.radiusEditField_2.Value;
            app.GeomList.StdPolyData(self_index).radius = radius;
            RePlotPolygon(app);
            RefreshSectionPlot(app);
        end

        % Value changed function: rotateangleEditField
        function rotateangleEditFieldValueChanged(app, event)
            r = getSelectedNameAndIndex(app);
            self_index = str2double(r(3));
            rotate_angle = app.rotateangleEditField.Value;
            app.GeomList.StdPolyData(self_index).rotate_angle = rotate_angle;
            RePlotPolygon(app);
            RefreshSectionPlot(app);
        end

        % Value changed function: EdgeNumberEditField
        function EdgeNumberEditFieldValueChanged(app, event)
            r = getSelectedNameAndIndex(app);
            self_index = str2double(r(3));
            edge_number = app.EdgeNumberEditField.Value;
            app.GeomList.StdPolyData(self_index).edge_number = edge_number;
            RePlotPolygon(app);
            RefreshSectionPlot(app);
        end

        % Button pushed function: EditPointsButton
        function EditPointsButtonPushed(app, event)
            r = getSelectedNameAndIndex(app);
            PolyName = r(1); self_index = str2double(r(3));
            InitData = app.GeomList.UserPolyData(self_index);
            Ask_Polygon_points(app, app.dark, PolyName, self_index, InitData);
        end

        % Button pushed function: BuildBeamGeometryButton
        function BuildBeamGeometryButtonPushed(app, event)
           try 
              ext1 = app.model.component('comp1').geom('geom1').feature.create('ext1', 'Extrude');
              ext1.selection('input').set({'wp1'});
              distance = char(num2str(app.ExtrudeEditField.Value));
              disp(distance);
              ext1.set('distance', {distance});
           catch  
               % if the geometry is not build or it has been extruded, do nothing
           end
           RefreshVolumePlot(app);
        end

        % Button pushed function: ClearExtrudeButton
        function ClearExtrudeButtonPushed(app, event)
            try
                app.model.component('comp1').geom('geom1').feature.remove('ext1');
            catch
            end
            RefreshVolumePlot(app);
        end

        % Button pushed function: MeshButton
        function MeshButtonPushed(app, event)
            MeshSize = app.DropDown.Value;
            try % if the mesh has been deleted, we need to recreate mesh 
                app.model.component('comp1').mesh.create('mesh1');
            catch % mesh has been created 
            end
                
            try
                SetMeshSize(app,MeshSize);
                app.model.component('comp1').mesh('mesh1').run()
                mphmesh(app.model);
            catch
                msgbox('geometry not ceated or mesh has been created');
            end
        end

        % Button pushed function: ClearMeshButton
        function ClearMeshButtonPushed(app, event)
            % note that it would not actually delete the mesh in component
            RefreshVolumePlot(app);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            Load = app.BoundaryConditionDropDown.Value;
            if Load == "Fixed Boundary"
                self_Index = AutoGenerateIndex2(app,'fix');
                FixName = ['fix',char(num2str(self_Index))];
                app.model.component('comp1').physics('solid').create(FixName, 'Fixed', 2);
                app.LoadListListBox.Items(length(app.LoadListListBox.Items) +1) = {FixName};
                % ------ Initialize the Load database-------
                app.LoadList.length = app.LoadList.length + 1;
                l = app.LoadList.length;
                app.LoadList.NameList(l) = string(FixName);
                app.LoadList.TypeList(l) = "fix";
                app.LoadList.IndexList(l) = self_Index;
                app.LoadList.FixedData(self_Index).selection = {};  % {''}, empty cell is no selection 
                % note that the storge of the item is {'a', 'b'};
            end
            if Load == "Roller"
                self_Index = AutoGenerateIndex2(app,'roll');
                rollName = ['roll',char(num2str(self_Index))];
                app.model.component('comp1').physics('solid').create(rollName, 'Roller', 2);
                app.LoadListListBox.Items(length(app.LoadListListBox.Items) + 1) = {rollName};
                % ----- Initialize the Roller Constraint ------
                app.LoadList.length = app.LoadList.length + 1;
                l = app.LoadList.length;
                app.LoadList.NameList(l) = string(rollName);
                app.LoadList.TypeList(l) = "roll";
                app.LoadList.IndexList(l) = self_Index;
                app.LoadList.RollerData(self_Index).selection = {};
            end
            if Load == "SymmetryConstraint"
                self_Index = AutoGenerateIndex2(app,'sym');
                SymName = ['sym',char(num2str(self_Index))];
                app.model.component('comp1').physics('solid').create(SymName, 'SymmetrySolid', 2);
                app.LoadListListBox.Items(length(app.LoadListListBox.Items) + 1) = {SymName};
                % ------ Initialize the Symmetry Constraint ----
                app.LoadList.length = app.LoadList.length + 1; 
                l = app.LoadList.length;
                app.LoadList.NameList(l) = string(SymName);
                app.LoadList.TypeList(l) = "sym";
                app.LoadList.IndexList(l) = self_Index;
                app.LoadList.SymmData(self_Index).selection = {};
            end
            if Load == "Boundary Load"
                self_Index = AutoGenerateIndex2(app,'bndl');
                BndName = ['bndl',char(num2str(self_Index))];
                app.model.component('comp1').physics('solid').create(BndName, 'BoundaryLoad', 2);
                app.LoadListListBox.Items(length(app.LoadListListBox.Items) + 1) = {BndName};
                % ------ 
                app.LoadList.length = app.LoadList.length + 1; 
                l = app.LoadList.length;
                app.LoadList.NameList(l) = string(BndName);
                app.LoadList.TypeList(l) = "bndl";
                app.LoadList.IndexList(l) = self_Index;
                app.LoadList.BdLoadData(self_Index).selection = {};
                app.LoadList.BdLoadData(self_Index).LoadType = "ForceArea";
                app.LoadList.BdLoadData(self_Index).LoadVeotor = [0,0,0];
            end
            if Load == "Volume Force"
                self_Index = AutoGenerateIndex2(app,'bl');
                VolfName = ['bl',char(num2str(self_Index))];
                app.model.component('comp1').physics('solid').create(VolfName, 'BodyLoad', 3);
                app.LoadListListBox.Items(length(app.LoadListListBox.Items) + 1) = {VolfName};
                % ------- 
                app.LoadList.length = app.LoadList.length + 1; 
                l = app.LoadList.length;
                app.LoadList.NameList(l) = string(VolfName);
                app.LoadList.TypeList(l) = "bl";
                app.LoadList.IndexList(l) = self_Index;
                app.LoadList.VolFData(self_Index).selection = {};
                app.LoadList.VolFData(self_Index).LoadVeotor = [0,0,0];
            end
        end

        % Button pushed function: CalculateStudyButton
        function CalculateStudyButtonPushed(app, event)
            % ----- it is used as a test, and then it would be modified -----
                disp("study run successfully");
                app.model.sol('sol1').runAll;
        end

        % Button pushed function: OpenModelInComsolButton
        function OpenModelInComsolButtonPushed(app, event)
            try
                disp("opening Comsol");
                mphsave("untitled.mph");
                mphlaunch;
            catch
                msgbox("open Comsol Failed");
            end
        end

        % Value changed function: LoadListListBox
        function LoadListListBoxValueChanged(app, event)
            % note that the autorefresh is included in the ValueChanged
            % Function
            r = getSelectedLoadNameAndIndex(app);
            Index = str2double(r(2)); self_index = str2double(r(3));
            % ----- get the name and the Index of the current selection
            % ---- firstly, we load the layout of Boundary settins panel
            RefreshBoundarySettings(app);
            % ---- then load the selections into the list -------
            if app.LoadList.TypeList(Index) == "fix"
                app.BoundarySelectionListBox.Value = app.LoadList.FixedData(self_index).selection;
                % -------------- judge what selection to display ----------
                selArr = str2double(string(app.LoadList.FixedData(self_index).selection));
                app.GlobalBoundarySelection.set(selArr);
                mphviewselection(app.model,'sel2','facecolorselected',[1 1 0],'facealpha',0.5);
                % second parameter is the tag of selection
            end
            if app.LoadList.TypeList(Index) == "roll"
                app.BoundarySelectionListBox.Value = app.LoadList.RollerData(self_index).selection;
                selArr = str2double(string(app.LoadList.RollerData(self_index).selection));
                app.GlobalBoundarySelection.set(selArr);
                mphviewselection(app.model,'sel2','facecolorselected',[1 1 0],'facealpha',0.5);
            end
            if app.LoadList.TypeList(Index) == "sym"
                app.BoundarySelectionListBox.Value = app.LoadList.SymmData(self_index).selection;
                selArr = str2double(string(app.LoadList.SymmData(self_index).selection));
                app.GlobalBoundarySelection.set(selArr);
                mphviewselection(app.model,'sel2','facecolorselected',[1 1 0],'facealpha',0.5);
            end
            if app.LoadList.TypeList(Index) == "bndl"
                % in the boundary load settings, both selections and the
                % Load Type(total force or force per area) should be changed
                app.BoundarySelectionListBox.Value = app.LoadList.BdLoadData(self_index).selection;
                selArr = str2double(string(app.LoadList.BdLoadData(self_index).selection));
                app.GlobalBoundarySelection.set(selArr);
                if app.LoadList.BdLoadData(self_index).LoadType == "ForceArea"
                    app.MethodDropDown.Value = "Force per unit area";
                else
                    app.MethodDropDown.Value = "Total Force";
                end
                mphviewselection(app.model,'sel2','facecolorselected',[1 1 0],'facealpha',0.5);
            end
            if app.LoadList.TypeList(Index) == "bl"
                app.DomainSelectionListBox.Value = app.LoadList.VolFData(self_index).selection;
                selArr = str2double(string(app.LoadList.VolFData(self_index).selection));
                disp (selArr);
                app.GlobalDomainSelection.set(selArr);
                % mphviewselection(app.model,'sel1');
            end
        end

        % Value changed function: DomainSelectionListBox
        function DomainSelectionListBoxValueChanged(app, event)
            ChooseValue = app.DomainSelectionListBox.Value;
            % firsly, we get the choice, then store it, then control the
            % selection , and finally refresh the display
            r = getSelectedLoadNameAndIndex(app);
            LoadName = r(1); self_Index = str2double(r(3));
            app.LoadList.VolFData(self_Index).selection = ChooseValue;
            app.model.component('comp1').physics('solid').feature(LoadName).selection.set(str2double(string(ChooseValue)));
            mphgeom();
            % There would be some problem in display, so we wouldn't
            % refresh
        end

        % Value changed function: BoundarySelectionListBox
        function BoundarySelectionListBoxValueChanged(app, event)
            ChooseValue = app.BoundarySelectionListBox.Value;
            r = getSelectedLoadNameAndIndex(app);
            LoadName = r(1); Index = str2double(r(2)); self_index = str2double(r(3));
            disp(Index);
            disp(app.LoadList.TypeList(1));
            % choose and storge
            Tp = app.LoadList.TypeList(1);
            % get the type of it to judge which database to store
            if Tp == "fix"
                app.LoadList.FixedData(self_index).selection = ChooseValue;
            end
            if Tp == "roll"
                app.LoadList.RollerData(self_index).selection = ChooseValue;
            end 
            if Tp == "sym"
                app.LoadList.SymmData(self_index).selection = ChooseValue;
            end
            if Tp == "bndl"
                app.LoadList.BdLoadData(self_index).selection = ChooseValue;
            end
            app.model.component('comp1').physics('solid').feature(LoadName).selection.set(str2double(string(ChooseValue)));
            app.GlobalBoundarySelection.set(str2double(string(ChooseValue)));
            mphviewselection(app.model,'sel2');
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            material = app.MaterialDropDown.Value;
            try
                app.model.component('comp1').material.create('mat1', 'Common');
            catch % if the material has been created;
                try
                    app.model.component('comp1').material.remove('mat1');
                    app.model.component('comp1');
                catch
                    error("can't create material,Check if your model is created");
                end
            end
            if material == "Structual Steel"
                app.model.component('comp1').material('mat1').propertyGroup.create('Enu', 'Young''s modulus and Poisson''s ratio');
                app.model.component('comp1').material('mat1').propertyGroup.create('Murnaghan', 'Murnaghan');
                app.model.component('comp1').material('mat1').propertyGroup.create('Lame', ['Lam' native2unicode(hex2dec({'00' 'e9'}), 'unicode') ' parameters']);
                app.model.component('comp1').material('mat1').label('Structural steel');
                app.model.component('comp1').material('mat1').set('family', 'custom');
                app.model.component('comp1').material('mat1').set('customspecular', [0.7843137254901961 0.7843137254901961 0.7843137254901961]);
                app.model.component('comp1').material('mat1').set('customdiffuse', [0.6666666666666666 0.6666666666666666 0.6666666666666666]);
                app.model.component('comp1').material('mat1').set('customambient', [0.6666666666666666 0.6666666666666666 0.6666666666666666]);
                app.model.component('comp1').material('mat1').set('noise', true);
                app.model.component('comp1').material('mat1').set('fresnel', 0.9);
                app.model.component('comp1').material('mat1').set('roughness', 0.3);
                app.model.component('comp1').material('mat1').set('metallic', 0);
                app.model.component('comp1').material('mat1').set('pearl', 0);
                app.model.component('comp1').material('mat1').set('diffusewrap', 0);
                app.model.component('comp1').material('mat1').set('clearcoat', 0);
                app.model.component('comp1').material('mat1').set('reflectance', 0);
                app.model.component('comp1').material('mat1').propertyGroup('def').set('relpermeability', {'1' '0' '0' '0' '1' '0' '0' '0' '1'});
                app.model.component('comp1').material('mat1').propertyGroup('def').set('heatcapacity', '475[J/(kg*K)]');
                app.model.component('comp1').material('mat1').propertyGroup('def').set('thermalconductivity', {'44.5[W/(m*K)]' '0' '0' '0' '44.5[W/(m*K)]' '0' '0' '0' '44.5[W/(m*K)]'});
                app.model.component('comp1').material('mat1').propertyGroup('def').set('electricconductivity', {'4.032e6[S/m]' '0' '0' '0' '4.032e6[S/m]' '0' '0' '0' '4.032e6[S/m]'});
                app.model.component('comp1').material('mat1').propertyGroup('def').set('relpermittivity', {'1' '0' '0' '0' '1' '0' '0' '0' '1'});
                app.model.component('comp1').material('mat1').propertyGroup('def').set('thermalexpansioncoefficient', {'12.3e-6[1/K]' '0' '0' '0' '12.3e-6[1/K]' '0' '0' '0' '12.3e-6[1/K]'});
                app.model.component('comp1').material('mat1').propertyGroup('def').set('density', '7850[kg/m^3]');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('E', '');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('nu', '');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('E', '');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('nu', '');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('E', '200e9[Pa]');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('nu', '0.30');
                app.model.component('comp1').material('mat1').propertyGroup('Murnaghan').set('l', '');
                app.model.component('comp1').material('mat1').propertyGroup('Murnaghan').set('m', '');
                app.model.component('comp1').material('mat1').propertyGroup('Murnaghan').set('n', '');
                app.model.component('comp1').material('mat1').propertyGroup('Murnaghan').set('l', '');
                app.model.component('comp1').material('mat1').propertyGroup('Murnaghan').set('m', '');
                app.model.component('comp1').material('mat1').propertyGroup('Murnaghan').set('n', '');
                app.model.component('comp1').material('mat1').propertyGroup('Murnaghan').set('l', '-3.0e11[Pa]');
                app.model.component('comp1').material('mat1').propertyGroup('Murnaghan').set('m', '-6.2e11[Pa]');
                app.model.component('comp1').material('mat1').propertyGroup('Murnaghan').set('n', '-7.2e11[Pa]');
                app.model.component('comp1').material('mat1').propertyGroup('Lame').set('lambLame', '');
                app.model.component('comp1').material('mat1').propertyGroup('Lame').set('muLame', '');
                app.model.component('comp1').material('mat1').propertyGroup('Lame').set('lambLame', '');
                app.model.component('comp1').material('mat1').propertyGroup('Lame').set('muLame', '');
                app.model.component('comp1').material('mat1').propertyGroup('Lame').set('lambLame', '1.15e11[Pa]');
                app.model.component('comp1').material('mat1').propertyGroup('Lame').set('muLame', '7.69e10[Pa]');
                app.model.component('comp1').material('mat1').set('family', 'custom');
                app.model.component('comp1').material('mat1').set('lighting', 'cooktorrance');
                app.model.component('comp1').material('mat1').set('fresnel', 0.9);
                app.model.component('comp1').material('mat1').set('roughness', 0.3);
                app.model.component('comp1').material('mat1').set('metallic', 0);
                app.model.component('comp1').material('mat1').set('pearl', 0);
                app.model.component('comp1').material('mat1').set('diffusewrap', 0);
                app.model.component('comp1').material('mat1').set('clearcoat', 0);
                app.model.component('comp1').material('mat1').set('reflectance', 0);
                app.model.component('comp1').material('mat1').set('ambient', 'custom');
                app.model.component('comp1').material('mat1').set('customambient', [0.6666666666666666 0.6666666666666666 0.6666666666666666]);
                app.model.component('comp1').material('mat1').set('diffuse', 'custom');
                app.model.component('comp1').material('mat1').set('customdiffuse', [0.6666666666666666 0.6666666666666666 0.6666666666666666]);
                app.model.component('comp1').material('mat1').set('specular', 'custom');
                app.model.component('comp1').material('mat1').set('customspecular', [0.7843137254901961 0.7843137254901961 0.7843137254901961]);
                app.model.component('comp1').material('mat1').set('noisecolor', 'custom');
                app.model.component('comp1').material('mat1').set('customnoisecolor', [0 0 0]);
                app.model.component('comp1').material('mat1').set('noisescale', 0);
                app.model.component('comp1').material('mat1').set('noise', 'off');
                app.model.component('comp1').material('mat1').set('noisefreq', 1);
                app.model.component('comp1').material('mat1').set('normalnoisebrush', '0');
                app.model.component('comp1').material('mat1').set('normalnoisetype', '0');
                app.model.component('comp1').material('mat1').set('alpha', 1);
            end
            if material == "Concrete"
                app.model.component('comp1').material('mat1').propertyGroup.create('Enu', 'Young''s modulus and Poisson''s ratio');
                app.model.component('comp1').material('mat1').label('Concrete');
                app.model.component('comp1').material('mat1').set('family', 'concrete');
                app.model.component('comp1').material('mat1').propertyGroup('def').set('thermalexpansioncoefficient', {'10e-6[1/K]' '0' '0' '0' '10e-6[1/K]' '0' '0' '0' '10e-6[1/K]'});
                app.model.component('comp1').material('mat1').propertyGroup('def').set('density', '2300[kg/m^3]');
                app.model.component('comp1').material('mat1').propertyGroup('def').set('thermalconductivity', {'1.8[W/(m*K)]' '0' '0' '0' '1.8[W/(m*K)]' '0' '0' '0' '1.8[W/(m*K)]'});
                app.model.component('comp1').material('mat1').propertyGroup('def').set('heatcapacity', '880[J/(kg*K)]');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('E', '');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('nu', '');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('E', '');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('nu', '');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('E', '25e9[Pa]');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('nu', '0.20');
                app.model.component('comp1').material('mat1').set('family', 'concrete');
            end
            if material == "Iron"
                app.model.component('comp1').material('mat1').propertyGroup.create('Enu', 'Young''s modulus and Poisson''s ratio');
                app.model.component('comp1').material('mat1').label('Iron');
                app.model.component('comp1').material('mat1').set('family', 'iron');
                app.model.component('comp1').material('mat1').propertyGroup('def').set('relpermeability', {'4000' '0' '0' '0' '4000' '0' '0' '0' '4000'});
                app.model.component('comp1').material('mat1').propertyGroup('def').set('electricconductivity', {'1.12e7[S/m]' '0' '0' '0' '1.12e7[S/m]' '0' '0' '0' '1.12e7[S/m]'});
                app.model.component('comp1').material('mat1').propertyGroup('def').set('thermalexpansioncoefficient', {'12.2e-6[1/K]' '0' '0' '0' '12.2e-6[1/K]' '0' '0' '0' '12.2e-6[1/K]'});
                app.model.component('comp1').material('mat1').propertyGroup('def').set('heatcapacity', '440[J/(kg*K)]');
                app.model.component('comp1').material('mat1').propertyGroup('def').set('relpermittivity', {'1' '0' '0' '0' '1' '0' '0' '0' '1'});
                app.model.component('comp1').material('mat1').propertyGroup('def').set('density', '7870[kg/m^3]');
                app.model.component('comp1').material('mat1').propertyGroup('def').set('thermalconductivity', {'76.2[W/(m*K)]' '0' '0' '0' '76.2[W/(m*K)]' '0' '0' '0' '76.2[W/(m*K)]'});
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('E', '');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('nu', '');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('E', '');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('nu', '');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('E', '200e9[Pa]');
                app.model.component('comp1').material('mat1').propertyGroup('Enu').set('nu', '0.29');
                app.model.component('comp1').material('mat1').set('family', 'iron');
            end
        end

        % Value changed function: PlotResultButton
        function PlotResultButtonValueChanged(app, event)
            % value = app.PlotResultButton.Value;
            app.model.result.create('pg1', 'PlotGroup3D');
            app.model.result('pg1').set('data', 'dset1');
            app.model.result('pg1').set('frametype', 'spatial');
            app.model.result('pg1').create('vol1', 'Volume');
            app.model.result('pg1').feature('vol1').set('expr', {'solid.mises'});
            app.model.result('pg1').feature('vol1').set('threshold', 'manual');
            app.model.result('pg1').feature('vol1').set('thresholdvalue', 0.2);
            app.model.result('pg1').feature('vol1').set('resolution', 'custom');
            app.model.result('pg1').feature('vol1').set('refine', 2);
            app.model.result('pg1').label('Stress (solid)');
            app.model.result('pg1').feature('vol1').set('colortable', 'Prism');
            app.model.result('pg1').feature('vol1').create('def', 'Deform');
            app.model.result('pg1').feature('vol1').feature('def').set('expr', {'u' 'v' 'w'});
            app.model.result('pg1').feature('vol1').feature('def').set('descr', 'Displacement field');
            app.model.result('pg1').run;
            mphplot(app.model,'pg1')
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            r = getSelectedLoadNameAndIndex(app);
            self_index = str2double(r(3));
            value = app.MethodDropDown.Value;
            % two load type, 'TotalForce' and
            if value == "Force per unit area"
                app.LoadList.BdLoadData(self_index).LoadType = 'ForceArea';
            end
            if value == "Total Force"
                app.LoadList.BdLoadData(self_index).LoadType = 'TotalForce';
            end
        end

        % Button pushed function: DefineForceVectorButton
        function DefineForceVectorButtonPushed(app, event)
            r = getSelectedLoadNameAndIndex(app);
            LoadName = r(1) ;Index = str2double(r(2)); self_index = str2double(r(3));
            LoadType = app.LoadList.TypeList(Index);
            vec = app.LoadList.BdLoadData(self_index).LoadVector;
            if  LoadType == "bndl"
                AskForForceVector(app,app.dark,vec,self_index,LoadName,"bndl");
            end
            if LoadType == "bl"
                AskForForceVector(app,app.dark,vec,self_index,LoadName,"bl");
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Tooltip = {''};
            app.HomeTab.Title = 'Home';
            app.HomeTab.Tag = 'homepage';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Tag = 'dark';
            app.Image.Tooltip = {'Twitter'};
            app.Image.ImageSource = 'Twitter_icon_night.png';
            app.Image2 = uiimage(app.HomeTab);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image2.Tag = 'light';
            app.Image2.Enable = 'off';
            app.Image2.Visible = 'off';
            app.Image2.Tooltip = {'Facebook'};
            app.Image2.ImageSource = 'Twitter_icon_day.png';
            app.Image3 = uiimage(app.HomeTab);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.Tag = 'dark';
            app.Image3.Tooltip = {'Twitter'};
            app.Image3.ImageSource = 'facebook_icon_night.png';
            app.Image4 = uiimage(app.HomeTab);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image4.Tag = 'light';
            app.Image4.Enable = 'off';
            app.Image4.Visible = 'off';
            app.Image4.ImageSource = 'facebook_icon_day.png';
            app.Image5 = uiimage(app.HomeTab);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image6Clicked, true);
            app.Image5.Tooltip = {'GitHub'};
            app.Image5.ImageSource = 'github_icon_night.png';
            app.Image6 = uiimage(app.HomeTab);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Image6Clicked, true);
            app.Image6.Enable = 'off';
            app.Image6.Visible = 'off';
            app.Image6.Tooltip = {'GitHub'};
            app.Image6.ImageSource = 'github_icon_day.png';
            app.Image7 = uiimage(app.HomeTab);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Image7Clicked, true);
            app.Image7.Tooltip = {'Bilibili'};
            app.Image7.ImageSource = 'bili_icon_night.png';
            app.Image8 = uiimage(app.HomeTab);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @Image7Clicked, true);
            app.Image8.Enable = 'off';
            app.Image8.Visible = 'off';
            app.Image8.Tooltip = {'Bilibili'};
            app.Image8.ImageSource = 'bili_icon_day.png';
            app.Image9 = uiimage(app.HomeTab);
            app.Image9.ImageClickedFcn = createCallbackFcn(app, @Image9Clicked, true);
            app.Image9.Tooltip = {'WeChat'};
            app.Image9.ImageSource = 'WeChat_icon_night.png';
            app.Image10 = uiimage(app.HomeTab);
            app.Image10.ImageClickedFcn = createCallbackFcn(app, @Image9Clicked, true);
            app.Image10.Enable = 'off';
            app.Image10.Visible = 'off';
            app.Image10.Tooltip = {'WeChat'};
            app.Image10.ImageSource = 'WeChat_icon_day.png';
            app.Image11 = uiimage(app.HomeTab);
            app.Image11.ImageClickedFcn = createCallbackFcn(app, @Image11Clicked, true);
            app.Image11.Tooltip = {'CSDN'};
            app.Image11.ImageSource = 'CSDN_icon_night.png';
            app.Image12 = uiimage(app.HomeTab);
            app.Image12.ImageClickedFcn = createCallbackFcn(app, @Image11Clicked, true);
            app.Image12.Enable = 'off';
            app.Image12.Visible = 'off';
            app.Image12.Tooltip = {'CSDN'};
            app.Image12.ImageSource = 'CSDN_icon_day.png';
            app.FollowmeonLabel = uilabel(app.HomeTab);
            app.FollowmeonLabel.Text = 'Follow me on  ';
            app.StartNewAnalyzationButton = uibutton(app.HomeTab, 'push');
            app.StartNewAnalyzationButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewFunc, true);
            app.StartNewAnalyzationButton.Text = 'Start New Analyzation';
            app.OpenexistinganalyzationButton = uibutton(app.HomeTab, 'push');
            app.OpenexistinganalyzationButton.Text = 'Open existing analyzation';
            app.PreferencesButton = uibutton(app.HomeTab, 'push');
            app.PreferencesButton.ButtonPushedFcn = createCallbackFcn(app, @PreferencesButtonPushed, true);
            app.PreferencesButton.Text = 'Preferences';
            app.LibraryButton = uibutton(app.HomeTab, 'push');
            app.LibraryButton.Text = 'Library';
            app.AboutButton = uibutton(app.HomeTab, 'push');
            app.AboutButton.Text = 'About';
            app.ExitButton = uibutton(app.HomeTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitFunc, true);
            app.ExitButton.Text = 'Exit';
            app.FEMAnalysisandOptimizationforBeamLabel = uilabel(app.HomeTab);
            app.FEMAnalysisandOptimizationforBeamLabel.Text = 'FEM Analysis and Optimization for Beam ';
            app.developedbyFriedParrotLabel = uilabel(app.HomeTab);
            app.developedbyFriedParrotLabel.Text = 'developed by    @FriedParrot';
            app.Image13 = uiimage(app.HomeTab);
            app.Image13.ImageSource = 'friedparrot.png';
            app.ModelingTab = uitab(app.TabGroup);
            app.ModelingTab.Title = 'Modeling';
            app.GeometryplotPanel = uipanel(app.ModelingTab);
            app.GeometryplotPanel.Title = 'Geometry plot';
            app.ViewSectionButton = uibutton(app.GeometryplotPanel, 'push');
            app.ViewSectionButton.ButtonPushedFcn = createCallbackFcn(app, @ViewSectionButtonPushed, true);
            app.ViewSectionButton.Text = 'View Section';
            app.BuildBeamGeometryButton = uibutton(app.GeometryplotPanel, 'push');
            app.BuildBeamGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @BuildBeamGeometryButtonPushed, true);
            app.BuildBeamGeometryButton.Text = 'Build Beam Geometry';
            app.ExtrudeEditField = uieditfield(app.GeometryplotPanel, 'numeric');
            app.ExtrudeEditField.LowerLimitInclusive = 'off';
            app.ExtrudeEditField.Limits = [0 Inf];
            app.ExtrudeEditField.Value = 1;
            app.ExtrudemmLabel = uilabel(app.GeometryplotPanel);
            app.ExtrudemmLabel.Text = 'Extrude(mm)';
            app.ClearExtrudeButton = uibutton(app.GeometryplotPanel, 'push');
            app.ClearExtrudeButton.ButtonPushedFcn = createCallbackFcn(app, @ClearExtrudeButtonPushed, true);
            app.ClearExtrudeButton.Text = 'Clear Extrude';
            app.CrossSectionPanel = uipanel(app.ModelingTab);
            app.CrossSectionPanel.Title = 'CrossSection';
            app.ListBox = uilistbox(app.CrossSectionPanel);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.AddComponentDropDown = uidropdown(app.CrossSectionPanel);
            app.AddComponentDropDown.Items = {'None', 'Rectangle', 'Circle', 'Ellipse', 'Polygon', 'Line Segments', ''};
            app.AddComponentDropDown.Value = 'None';
            app.CreateButton = uibutton(app.CrossSectionPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.PlaneGeometryListLabel = uilabel(app.CrossSectionPanel);
            app.PlaneGeometryListLabel.Text = ' Plane Geometry List';
            app.PropertiesPanel = uipanel(app.ModelingTab);
            app.PropertiesPanel.Title = 'Properties';
            app.NoPropertytoDisplayLabel = uilabel(app.PropertiesPanel);
            app.NoPropertytoDisplayLabel.Text = 'No Property to Display';
            app.UserDefinedPolygonPanel = uipanel(app.PropertiesPanel);
            app.UserDefinedPolygonPanel.Enable = 'off';
            app.UserDefinedPolygonPanel.Title = 'UserDefinedPolygon';
            app.UserDefinedPolygonPanel.Visible = 'off';
            app.EditPointsButton = uibutton(app.UserDefinedPolygonPanel, 'push');
            app.EditPointsButton.ButtonPushedFcn = createCallbackFcn(app, @EditPointsButtonPushed, true);
            app.EditPointsButton.Text = 'Edit Points';
            app.DeleteButton_4 = uibutton(app.UserDefinedPolygonPanel, 'push');
            app.DeleteButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeleteGeometry, true);
            app.DeleteButton_4.Text = 'Delete';
            app.RectanglePanel = uipanel(app.PropertiesPanel);
            app.RectanglePanel.Enable = 'off';
            app.RectanglePanel.Title = 'Rectangle';
            app.RectanglePanel.Visible = 'off';
            app.widthEditFieldLabel = uilabel(app.RectanglePanel);
            app.widthEditFieldLabel.Text = 'width';
            app.widthEditField = uieditfield(app.RectanglePanel, 'numeric');
            app.widthEditField.Limits = [0 Inf];
            app.widthEditField.ValueChangedFcn = createCallbackFcn(app, @widthEditFieldValueChanged, true);
            app.HeightEditFieldLabel = uilabel(app.RectanglePanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.RectanglePanel, 'numeric');
            app.HeightEditField.Limits = [0 Inf];
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.xEditFieldLabel = uilabel(app.RectanglePanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.RectanglePanel, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.RectanglePanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.RectanglePanel, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.locatemethodDropDownLabel = uilabel(app.RectanglePanel);
            app.locatemethodDropDownLabel.Text = 'locate method';
            app.locatemethodDropDown = uidropdown(app.RectanglePanel);
            app.locatemethodDropDown.Items = {'Center', 'Corner'};
            app.locatemethodDropDown.ValueChangedFcn = createCallbackFcn(app, @locatemethodDropDownValueChanged, true);
            app.locatemethodDropDown.Value = 'Center';
            app.DeleteButton = uibutton(app.RectanglePanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteGeometry, true);
            app.DeleteButton.Text = 'Delete';
            app.CirclePanel = uipanel(app.PropertiesPanel);
            app.CirclePanel.Enable = 'off';
            app.CirclePanel.Title = 'Circle';
            app.CirclePanel.Visible = 'off';
            app.xEditField_2Label = uilabel(app.CirclePanel);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.CirclePanel, 'numeric');
            app.xEditField_2.ValueChangedFcn = createCallbackFcn(app, @xEditField_2ValueChanged, true);
            app.yEditField_2Label = uilabel(app.CirclePanel);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.CirclePanel, 'numeric');
            app.yEditField_2.ValueChangedFcn = createCallbackFcn(app, @yEditField_2ValueChanged, true);
            app.CenterlocationLabel = uilabel(app.CirclePanel);
            app.CenterlocationLabel.Text = 'Center location';
            app.radiusEditFieldLabel = uilabel(app.CirclePanel);
            app.radiusEditFieldLabel.Text = 'radius';
            app.radiusEditField = uieditfield(app.CirclePanel, 'numeric');
            app.radiusEditField.ValueChangedFcn = createCallbackFcn(app, @radiusEditFieldValueChanged, true);
            app.DeleteButton_2 = uibutton(app.CirclePanel, 'push');
            app.DeleteButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteGeometry, true);
            app.DeleteButton_2.Text = 'Delete';
            app.EllipsePanel = uipanel(app.PropertiesPanel);
            app.EllipsePanel.Enable = 'off';
            app.EllipsePanel.Title = 'Ellipse';
            app.EllipsePanel.Visible = 'off';
            app.xEditField_3Label = uilabel(app.EllipsePanel);
            app.xEditField_3Label.Text = 'x';
            app.xEditField_3 = uieditfield(app.EllipsePanel, 'numeric');
            app.xEditField_3.ValueChangedFcn = createCallbackFcn(app, @xEditField_3ValueChanged, true);
            app.yEditField_3Label = uilabel(app.EllipsePanel);
            app.yEditField_3Label.Text = 'y';
            app.yEditField_3 = uieditfield(app.EllipsePanel, 'numeric');
            app.yEditField_3.ValueChangedFcn = createCallbackFcn(app, @yEditField_3ValueChanged, true);
            app.CenterLabel = uilabel(app.EllipsePanel);
            app.CenterLabel.Text = 'Center';
            app.MajorradiusEditFieldLabel = uilabel(app.EllipsePanel);
            app.MajorradiusEditFieldLabel.Text = 'Major radius';
            app.MajorradiusEditField = uieditfield(app.EllipsePanel, 'numeric');
            app.MajorradiusEditField.ValueChangedFcn = createCallbackFcn(app, @MajorradiusEditFieldValueChanged, true);
            app.MinorradiusEditFieldLabel = uilabel(app.EllipsePanel);
            app.MinorradiusEditFieldLabel.Text = 'Minor radius';
            app.MinorradiusEditField = uieditfield(app.EllipsePanel, 'numeric');
            app.MinorradiusEditField.ValueChangedFcn = createCallbackFcn(app, @MinorradiusEditFieldValueChanged, true);
            app.RotateangleEditFieldLabel = uilabel(app.EllipsePanel);
            app.RotateangleEditFieldLabel.Text = 'Rotate angle';
            app.RotateangleEditField = uieditfield(app.EllipsePanel, 'numeric');
            app.RotateangleEditField.ValueChangedFcn = createCallbackFcn(app, @RotateangleEditFieldValueChanged, true);
            app.DeleteButton_3 = uibutton(app.EllipsePanel, 'push');
            app.DeleteButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteGeometry, true);
            app.DeleteButton_3.Text = 'Delete';
            app.StandardPolygonPanel = uipanel(app.PropertiesPanel);
            app.StandardPolygonPanel.Enable = 'off';
            app.StandardPolygonPanel.Title = 'StandardPolygon';
            app.StandardPolygonPanel.Visible = 'off';
            app.EdgeNumberEditFieldLabel = uilabel(app.StandardPolygonPanel);
            app.EdgeNumberEditFieldLabel.Text = 'Edge Number';
            app.EdgeNumberEditField = uieditfield(app.StandardPolygonPanel, 'numeric');
            app.EdgeNumberEditField.Limits = [3 Inf];
            app.EdgeNumberEditField.ValueChangedFcn = createCallbackFcn(app, @EdgeNumberEditFieldValueChanged, true);
            app.EdgeNumberEditField.Value = 3;
            app.radiusEditField_2Label = uilabel(app.StandardPolygonPanel);
            app.radiusEditField_2Label.Text = 'radius ';
            app.radiusEditField_2 = uieditfield(app.StandardPolygonPanel, 'numeric');
            app.radiusEditField_2.LowerLimitInclusive = 'off';
            app.radiusEditField_2.Limits = [0 Inf];
            app.radiusEditField_2.ValueChangedFcn = createCallbackFcn(app, @radiusEditField_2ValueChanged, true);
            app.radiusEditField_2.Value = 1;
            app.CreatingMethodDropDownLabel = uilabel(app.StandardPolygonPanel);
            app.CreatingMethodDropDownLabel.Text = 'Creating Method';
            app.CreatingMethodDropDown = uidropdown(app.StandardPolygonPanel);
            app.CreatingMethodDropDown.Items = {'Circumscribed circle', 'Inscribed circle'};
            app.CreatingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CreatingMethodDropDownValueChanged, true);
            app.CreatingMethodDropDown.Value = 'Circumscribed circle';
            app.deleteButton = uibutton(app.StandardPolygonPanel, 'push');
            app.deleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteGeometry, true);
            app.deleteButton.Text = 'delete';
            app.rotateangleEditFieldLabel = uilabel(app.StandardPolygonPanel);
            app.rotateangleEditFieldLabel.Text = 'rotate angle';
            app.rotateangleEditField = uieditfield(app.StandardPolygonPanel, 'numeric');
            app.rotateangleEditField.ValueChangedFcn = createCallbackFcn(app, @rotateangleEditFieldValueChanged, true);
            app.MeshSettingsPanel = uipanel(app.ModelingTab);
            app.MeshSettingsPanel.Title = 'MeshSettings';
            app.MeshButton = uibutton(app.MeshSettingsPanel, 'push');
            app.MeshButton.ButtonPushedFcn = createCallbackFcn(app, @MeshButtonPushed, true);
            app.MeshButton.Text = 'Mesh';
            app.ClearMeshButton = uibutton(app.MeshSettingsPanel, 'push');
            app.ClearMeshButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMeshButtonPushed, true);
            app.ClearMeshButton.Text = {'Clear'; 'Mesh'};
            app.DropDown = uidropdown(app.MeshSettingsPanel);
            app.DropDown.Items = {'Normal', 'Fine', 'Finer', 'Extra Fine', 'Extremely Fine', 'Coarse', 'Coarser', 'Extra Coarse', 'Extremely Coarse'};
            app.DropDown.Value = 'Normal';
            app.MeshQualityControlLabel = uilabel(app.MeshSettingsPanel);
            app.MeshQualityControlLabel.Text = 'Mesh Quality Control';
            app.PhysicsDefinitionTab = uitab(app.TabGroup);
            app.PhysicsDefinitionTab.Title = 'Physics Definition';
            app.BoundaryConditionPanel = uipanel(app.PhysicsDefinitionTab);
            app.BoundaryConditionPanel.Title = 'Boundary Condition';
            app.LoadListListBoxLabel = uilabel(app.BoundaryConditionPanel);
            app.LoadListListBoxLabel.Text = 'LoadList';
            app.LoadListListBox = uilistbox(app.BoundaryConditionPanel);
            app.LoadListListBox.Items = {};
            app.LoadListListBox.ValueChangedFcn = createCallbackFcn(app, @LoadListListBoxValueChanged, true);
            app.LoadListListBox.Value = {};
            app.BoundaryConditionDropDown = uidropdown(app.BoundaryConditionPanel);
            app.BoundaryConditionDropDown.Items = {'Fixed Boundary', 'Roller', 'SymmetryConstraint', 'Boundary Load', 'Volume Force'};
            app.BoundaryConditionDropDown.Value = 'Fixed Boundary';
            app.AddButton = uibutton(app.BoundaryConditionPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton_5 = uibutton(app.BoundaryConditionPanel, 'push');
            app.DeleteButton_5.Text = 'Delete';
            app.MoreOptionsPanel = uipanel(app.PhysicsDefinitionTab);
            app.MoreOptionsPanel.Title = 'More Options';
            app.OpenModelInComsolButton = uibutton(app.MoreOptionsPanel, 'push');
            app.OpenModelInComsolButton.ButtonPushedFcn = createCallbackFcn(app, @OpenModelInComsolButtonPushed, true);
            app.OpenModelInComsolButton.Text = 'Open Model In Comsol';
            app.SaveModelButton = uibutton(app.MoreOptionsPanel, 'push');
            app.SaveModelButton.Text = 'Save Model';
            app.BoundarySettingsPanel = uipanel(app.PhysicsDefinitionTab);
            app.BoundarySettingsPanel.Title = 'Boundary Settings';
            app.SelectAllButton = uibutton(app.BoundarySettingsPanel, 'push');
            app.SelectAllButton.Enable = 'off';
            app.SelectAllButton.Visible = 'off';
            app.SelectAllButton.Text = 'Select All';
            app.ClearButton = uibutton(app.BoundarySettingsPanel, 'push');
            app.ClearButton.Enable = 'off';
            app.ClearButton.Visible = 'off';
            app.ClearButton.Text = 'Clear';
            app.BoundarySelectionListBox = uilistbox(app.BoundarySettingsPanel);
            app.BoundarySelectionListBox.Items = {};
            app.BoundarySelectionListBox.Multiselect = 'on';
            app.BoundarySelectionListBox.ValueChangedFcn = createCallbackFcn(app, @BoundarySelectionListBoxValueChanged, true);
            app.BoundarySelectionListBox.Enable = 'off';
            app.BoundarySelectionListBox.Visible = 'off';
            app.BoundarySelectionListBox.Value = {};
            app.ReplotSelectionButton = uibutton(app.BoundarySettingsPanel, 'push');
            app.ReplotSelectionButton.Enable = 'off';
            app.ReplotSelectionButton.Visible = 'off';
            app.ReplotSelectionButton.Text = 'Replot Selection';
            app.DefineLoadvectorPanel = uipanel(app.BoundarySettingsPanel);
            app.DefineLoadvectorPanel.Enable = 'off';
            app.DefineLoadvectorPanel.Title = 'Define Load vector';
            app.DefineLoadvectorPanel.Visible = 'off';
            app.MethodDropDownLabel = uilabel(app.DefineLoadvectorPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.DefineLoadvectorPanel);
            app.MethodDropDown.Items = {'Force per unit area', 'Total Force'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Force per unit area';
            app.DefineForceVectorButton = uibutton(app.DefineLoadvectorPanel, 'push');
            app.DefineForceVectorButton.ButtonPushedFcn = createCallbackFcn(app, @DefineForceVectorButtonPushed, true);
            app.DefineForceVectorButton.Text = 'Define Force Vector';
            app.DomainSelectionListBox = uilistbox(app.BoundarySettingsPanel);
            app.DomainSelectionListBox.Items = {};
            app.DomainSelectionListBox.Multiselect = 'on';
            app.DomainSelectionListBox.ValueChangedFcn = createCallbackFcn(app, @DomainSelectionListBoxValueChanged, true);
            app.DomainSelectionListBox.Enable = 'off';
            app.DomainSelectionListBox.Visible = 'off';
            app.DomainSelectionListBox.Value = {};
            app.NothingtoDisplayLabel = uilabel(app.BoundarySettingsPanel);
            app.NothingtoDisplayLabel.Text = 'Nothing to Display';
            app.BoundarySelectionLabel = uilabel(app.BoundarySettingsPanel);
            app.BoundarySelectionLabel.Enable = 'off';
            app.BoundarySelectionLabel.Visible = 'off';
            app.BoundarySelectionLabel.Text = 'Boundary Selection';
            app.StudyCalculationPanel = uipanel(app.PhysicsDefinitionTab);
            app.StudyCalculationPanel.Title = 'Study Calculation';
            app.IncludeGeometricNonlinearityCheckBox = uicheckbox(app.StudyCalculationPanel);
            app.IncludeGeometricNonlinearityCheckBox.Enable = 'off';
            app.IncludeGeometricNonlinearityCheckBox.Text = 'Include Geometric Nonlinearity';
            app.CalculateStudyButton = uibutton(app.StudyCalculationPanel, 'push');
            app.CalculateStudyButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateStudyButtonPushed, true);
            app.CalculateStudyButton.Text = 'Calculate Study';
            app.MaterialDropDownLabel = uilabel(app.StudyCalculationPanel);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.StudyCalculationPanel);
            app.MaterialDropDown.Items = {'Structual Steel', 'Concrete', 'Iron', 'Copper'};
            app.MaterialDropDown.Value = 'Structual Steel';
            app.ConfirmButton = uibutton(app.StudyCalculationPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.optimizationTab = uitab(app.TabGroup);
            app.optimizationTab.Title = 'optimization';
            app.TheOptimizationTabiscurrentlyonconstrucionLabel = uilabel(app.optimizationTab);
            app.TheOptimizationTabiscurrentlyonconstrucionLabel.Text = 'The Optimization Tab is currently on construcion';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UIAxes = uiaxes(app.ResultsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotSettingsPanel = uipanel(app.ResultsTab);
            app.PlotSettingsPanel.Title = 'PlotSettings';
            app.PlotDeformationCheckBox = uicheckbox(app.PlotSettingsPanel);
            app.PlotDeformationCheckBox.Text = 'Plot Deformation';
            app.DeformationfactorEditFieldLabel = uilabel(app.PlotSettingsPanel);
            app.DeformationfactorEditFieldLabel.Text = 'Deformation factor';
            app.DeformationfactorEditField = uieditfield(app.PlotSettingsPanel, 'numeric');
            app.PlotParameterDropDownLabel = uilabel(app.PlotSettingsPanel);
            app.PlotParameterDropDownLabel.Text = 'Plot Parameter';
            app.PlotParameterDropDown = uidropdown(app.PlotSettingsPanel);
            app.ColorMapDropDownLabel = uilabel(app.PlotSettingsPanel);
            app.ColorMapDropDownLabel.Text = 'ColorMap';
            app.ColorMapDropDown = uidropdown(app.PlotSettingsPanel);
            app.ColorMapDropDown.Items = {'Default', 'Option 2', 'Option 3', 'Option 4'};
            app.ColorMapDropDown.Value = 'Default';
            app.PlotResultButton = uibutton(app.ResultsTab, 'state');
            app.PlotResultButton.ValueChangedFcn = createCallbackFcn(app, @PlotResultButtonValueChanged, true);
            app.PlotResultButton.Text = 'Plot Result';
            app.SavepictureButton = uibutton(app.ResultsTab, 'state');
            app.SavepictureButton.Text = 'Save picture';
            app.helpTab = uitab(app.TabGroup);
            app.helpTab.Title = 'help';
            app.ExamplesLibraryButton = uibutton(app.helpTab, 'push');
            app.ExamplesLibraryButton.Text = {'Examples '; '& Library'};
            app.GuideDocumentationButton = uibutton(app.helpTab, 'push');
            app.GuideDocumentationButton.Text = {'Guide'; '& Documentation'};
            app.ReportBugsContactusButton = uibutton(app.helpTab, 'push');
            app.ReportBugsContactusButton.Text = {'Report Bugs & '; 'Contact us'};
            app.Image14 = uiimage(app.helpTab);
            app.Image14.Enable = 'off';
            app.Image14.Visible = 'off';
            app.Image14.ImageSource = 'docs_icon_day.png';
            app.Image15 = uiimage(app.helpTab);
            app.Image15.Enable = 'off';
            app.Image15.Visible = 'off';
            app.Image15.ImageSource = 'lib_icon_day.png';
            app.Image16 = uiimage(app.helpTab);
            app.Image16.Enable = 'off';
            app.Image16.Visible = 'off';
            app.Image16.ImageSource = 'us_icon_day.png';
            app.Image17 = uiimage(app.helpTab);
            app.Image17.ImageSource = 'lib_icon_night.jpg';
            app.Image18 = uiimage(app.helpTab);
            app.Image18.ImageSource = 'docs_icon_night.png';
            app.Image19 = uiimage(app.helpTab);
            app.Image19.ImageSource = 'us_icon_night.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Beam_analyzation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end