classdef AskForForceVector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        zEditField                matlab.ui.control.NumericEditField
        zEditFieldLabel           matlab.ui.control.Label
        yEditField                matlab.ui.control.NumericEditField
        yEditFieldLabel           matlab.ui.control.Label
        xEditField                matlab.ui.control.NumericEditField
        xEditFieldLabel           matlab.ui.control.Label
        CancelButton              matlab.ui.control.Button
        OKButton                  matlab.ui.control.Button
        InputtheForceVectorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingAPP % Description
        dark = 1 % Description
        ForceVector = [0,0,0] % Description
        LoadType = "" % Description
        LoadSelfIndex % Description
        LoadTotName % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainAPP, darkvalue, InitialVector, LoadSelfIndex, LoadTotName, LoadType)
            app.CallingAPP = mainAPP;
            app.dark = darkvalue;
            app.LoadType = LoadType;
            app.LoadSelfIndex = LoadSelfIndex;
            app.LoadTotName = LoadTotName;
            app.ForceVector = InitialVector;
            app.xEditField.Value = InitialVector(1);
            app.yEditField.Value = InitialVector(2);
            app.zEditField.Value = InitialVector(3);
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            y = app.ForceVector(2); z = app.ForceVector(3);
            x = app.xEditField.Value;
            app.ForceVector = [x,y,z];
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            y = app.yEditField.Value;
            x = app.ForceVector(1); z = app.ForceVector(3);
            app.ForceVector = [x,y,z];
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            z = app.zEditField.Value;
            x = app.ForceVector(1); y = app.ForceVector(2);
            app.ForceVector = [x,y,z];
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            SetForceVector(app.CallingAPP,app.ForceVector,app.LoadSelfIndex,app.LoadType,app.LoadTotName);
            app.delete();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputtheForceVectorLabel = uilabel(app.UIFigure);
            app.InputtheForceVectorLabel.Text = 'Input the Force Vector';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AskForForceVector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end