classdef Ask_Polygon_points < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InputthepointofthePolygonLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        OKButton                        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        yLabel                          matlab.ui.control.Label
        xLabel                          matlab.ui.control.Label
        PointsLabel                     matlab.ui.control.Label
        RemoveButton                    matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        PointListListBox                matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        dark = 1   % Description
        CallingAPP % Description
        PointTable = zeros(3,2) % a pointNum x 2 table, which storge the coordinate of the points
        PolyName % Description
        PolyIndex % Description
        mode = "create" % Description
    end
    
    methods (Access = private)
        function ChangeDisplayTheme(app)
            if app.dark == 1 % in dark mode
                app.UIFigure.Color = [0.25, 0.24, 0.26];
                app.Panel.BackgroundColor = [0.50, 0.50,0.50];
                app.InputthepointofthePolygonLabel.FontColor = [0.65,0.65,0.65];
            else
                app.UIFigure.Color = [0.94, 0.94, 0.94];
                app.Panel.BackgroundColor = [0.90, 0.90,0.90];
                app.InputthepointofthePolygonLabel.FontColor = [0.00,0.00,0.00];
            end
        end
        
        function RefreshPointDisplay(app)
            % display the current point
            i = CurrentPointIndex(app);
            app.EditField.Value = app.PointTable(i,1);
            app.EditField_2.Value = app.PointTable(i,2);
        end
        
        function results = CurrentPointIndex(app)
            CurrentPoint = app.PointListListBox.Value;
            for i = 1: length(app.PointListListBox.Items)
                if string(app.PointListListBox.Items(i)) == string(CurrentPoint)
                    break
                end
            end
            results = i;
        end
        
        function InitializeTable(app,InitTable)
            app.PointTable = InitTable.PointTable;
            app.PointListListBox.Items = InitTable.Items;
            RefreshPointDisplay(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainAPP, darkvalue, PolyName, PolyIndex, InitTable)
            app.PolyName = PolyName;
            app.PolyIndex = PolyIndex;
            app.CallingAPP = mainAPP;
            app.dark = darkvalue;
            try
                % create mode, which won't initialize the point
                app.mode = "create";
                isnan(InitTable)
            catch
                % initialize the point
                app.mode = "edit";
                InitializeTable(app,InitTable);
                % *************************** 
            end
            ChangeDisplayTheme(app);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            l = length(app.PointListListBox.Items);
            Arr = split(string(app.PointListListBox.Items(l)));
            maxIndex = str2double(Arr(2));
            NewPointName = ['Point ', num2str(maxIndex +1)];
            app.PointListListBox.Items(l+1) = {NewPointName};
            app.PointTable(l+1,:) = [0,0];  % initialize the coordinates of a new point
            % then we change the current editing point to this point
            app.PointListListBox.Value = {NewPointName};
            RefreshPointDisplay(app);
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            l = length(app.PointListListBox.Items());
            if l <= 3
                msgbox('at least 3 points are needed in a section'); 
            else
                CurrentPoint = app.PointListListBox.Value;
                for i = 1: length(app.PointListListBox.Items)
                    if string(app.PointListListBox.Items(i)) == string(CurrentPoint)
                        % delete the point 
                        app.PointListListBox.Items(i) = [];
                        app.PointTable(i,:) = [];
                        break
                    end
                end
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            i = CurrentPointIndex(app);
            try
                app.PointTable(i, 1) = value;
            catch
                app.PointTable(i, 1) = 0; 
                msgbox("Check your input format");
            end
            RefreshPointDisplay(app);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            i = CurrentPointIndex(app);
            try
                app.PointTable(i, 2) = value;
            catch
                app.PointTable(i, 2) = 0; 
                msgbox("Check your input format");
            end
            RefreshPointDisplay(app);
        end

        % Value changed function: PointListListBox
        function PointListListBoxValueChanged(app, event)
            RefreshPointDisplay(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.mode == "create"
                Items = app.PointListListBox.Items;
                CreatePolygen(app.CallingAPP, app.PointTable, app.PolyName, app.PolyIndex, Items);
                app.delete();
            end
            try
                if app.mode == "edit"
                % -----------
                    EditPolygen(app.CallingAPP,app.PolyName,app.PointTable,app.PointListListBox.Items,app.PolyIndex);
                    app.delete();
                end
            catch % app has been deleted
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete(); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.PointListListBox = uilistbox(app.Panel);
            app.PointListListBox.Items = {'point 1', 'point 2', 'point 3'};
            app.PointListListBox.ValueChangedFcn = createCallbackFcn(app, @PointListListBoxValueChanged, true);
            app.PointListListBox.Value = 'point 1';
            app.AddButton = uibutton(app.Panel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.Panel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.PointsLabel = uilabel(app.Panel);
            app.PointsLabel.Text = 'Points';
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.Panel);
            app.yLabel.Text = 'y';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.InputthepointofthePolygonLabel = uilabel(app.UIFigure);
            app.InputthepointofthePolygonLabel.Text = 'Input the point of the Polygon';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ask_Polygon_points(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end