classdef ModelNotCreatedDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TheModelisnotcreatedyetReturnHomeorCreateaNewmodelnowLabel  matlab.ui.control.Label
        CreatingNewModelButton  matlab.ui.control.Button
        ReturnHomeButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        dark = 1 % Description
        CallingAPP % Description
    end
    
    methods (Access = private)
        function RefrershMode(app)
            if app.dark == 1 % in dark mode
                app.UIFigure.Color = [0.25, 0.24, 0.26];
                app.TheModelisnotcreatedyetReturnHomeorCreateaNewmodelnowLabel.FontColor = [0.65,0.65,0.65];
            else 
                app.UIFigure.Color = [0.90, 0.90, 0.90];
                app.TheModelisnotcreatedyetReturnHomeorCreateaNewmodelnowLabel.FontColor = [0.00,0.00,0.00];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainAPP, darkvalue)
            app.CallingAPP = mainAPP;
            app.dark = darkvalue;
            RefrershMode(app);
        end

        % Button pushed function: CreatingNewModelButton
        function CreatingNewModelButtonPushed(app, event)
            app.CallingAPP.CreateNewModel();
            app.delete();
        end

        % Button pushed function: ReturnHomeButton
        function ReturnHomeButtonPushed(app, event)
            app.CallingAPP.ReturnHome();
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReturnHomeButton = uibutton(app.UIFigure, 'push');
            app.ReturnHomeButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnHomeButtonPushed, true);
            app.ReturnHomeButton.Text = 'Return Home';
            app.CreatingNewModelButton = uibutton(app.UIFigure, 'push');
            app.CreatingNewModelButton.ButtonPushedFcn = createCallbackFcn(app, @CreatingNewModelButtonPushed, true);
            app.CreatingNewModelButton.Text = 'Creating New Model';
            app.TheModelisnotcreatedyetReturnHomeorCreateaNewmodelnowLabel = uilabel(app.UIFigure);
            app.TheModelisnotcreatedyetReturnHomeorCreateaNewmodelnowLabel.Text = {'The Model is not created yet. '; 'Return Home or Create a New model now. '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelNotCreatedDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end