classdef Settings_Dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        OKButton         matlab.ui.control.Button
        PropertiesPanel  matlab.ui.container.Panel
        DarkModeLabel    matlab.ui.control.Label
        Switch           matlab.ui.control.Switch
    end

    
    properties (Access = private)
        dark = 1
        CallingAPP;
    end
    
    methods (Access = private)
        function RefreshDisplay(app, darkvalue)
            if darkvalue == 0 % light mode
                app.UIFigure. Color = [0.90,0.90,0.90];
                app.PropertiesPanel.BackgroundColor = [0.90,0.90,0.90];
                app.Switch.Value = 'Off';
            else 
                app.UIFigure. Color = [0.25,0.24,0.26];
                app.PropertiesPanel.BackgroundColor = [0.25,0.24,0.26];
                app.Switch.Value = 'On';
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, darkvalue)
            app.CallingAPP = mainapp;
            app.dark = darkvalue;
            RefreshDisplay(app, app.dark);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.get("Value");
            if value == "On"
                app.dark = 1;
            else
                app.dark = 0;
            end
            RefreshDisplay(app, app.dark); % Refresh the self-display
            ToggleDarkMode(app.CallingAPP, app.dark);
            % call this function directly
            % note that we can transfer the callingAPP there as the
            % original app
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            ExitFcn(app,event);
        end

        % Close request function: UIFigure
        function ExitFcn(app, event)
            try
                app.CallingAPP.PreferencesButton.Enable = 'on';
            catch
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @ExitFcn, true);
            app.PropertiesPanel = uipanel(app.UIFigure);
            app.PropertiesPanel.Title = 'Properties';
            app.Switch = uiswitch(app.PropertiesPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'On';
            app.DarkModeLabel = uilabel(app.PropertiesPanel);
            app.DarkModeLabel.Text = 'Dark Mode';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Settings_Dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end